classdef SQLite_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uitoolbar1                      matlab.ui.container.Toolbar
        uipushtool2                     matlab.ui.container.toolbar.PushTool
        ExportDatatoCSV                 matlab.ui.control.Button
        EditNameButton                  matlab.ui.control.Button
        NameLabel                       matlab.ui.control.Label
        CSVImportButton                 matlab.ui.control.Button
        selDbPanel                      matlab.ui.container.Panel
        RevertChanges                   matlab.ui.control.Button
        AddSqliteFiles                  matlab.ui.control.Button
        selectDbMenu                    matlab.ui.control.DropDown
        uipanel7                        matlab.ui.container.Panel
        AverageRowsCheckBox             matlab.ui.control.CheckBox
        YesSumColumnNoAverageColumnCheckBox  matlab.ui.control.CheckBox
        YAxisParametersFilterEditField  matlab.ui.control.EditField
        YAxisParametersFilterPhraseEditFieldLabel  matlab.ui.control.Label
        XAxisParametersFilterEditField  matlab.ui.control.EditField
        XAxisParametersFilterPhraseEditFieldLabel  matlab.ui.control.Label
        logYCheckbox                    matlab.ui.control.CheckBox
        logXCheckbox                    matlab.ui.control.CheckBox
        text31                          matlab.ui.control.Label
        y2GroupButton                   matlab.ui.control.Button
        text24                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        yAxisParamList2                 matlab.ui.control.ListBox
        xAxisParamList2                 matlab.ui.control.ListBox
        yAxisParamList                  matlab.ui.control.ListBox
        xAxisText                       matlab.ui.control.Label
        xAxisParamList                  matlab.ui.control.ListBox
        uipanel1                        matlab.ui.container.Panel
        plotTypeDropdown                matlab.ui.control.DropDown
        uipanel4                        matlab.ui.container.Panel
        OnlyIncludeSameTableFiltersCheckBox  matlab.ui.control.CheckBox
        uipanel14                       matlab.ui.container.Panel
        ignoreZerosCheckbox             matlab.ui.control.CheckBox
        clearNarrowButton               matlab.ui.control.Button
        narrowedList                    matlab.ui.control.ListBox
        text28                          matlab.ui.control.Label
        uipanel15                       matlab.ui.container.Panel
        addCategoryButton               matlab.ui.control.Button
        divideEditText                  matlab.ui.control.EditField
        divideSlider                    matlab.ui.control.Slider
        inclCategoriesList              matlab.ui.control.ListBox
        onlyIncludeText                 matlab.ui.control.Label
        plotButton                      matlab.ui.control.Button
        markerColorDropdown             matlab.ui.control.DropDown
        markerTypeText                  matlab.ui.control.Label
        markerTypeDropdown              matlab.ui.control.DropDown
        markerColorText                 matlab.ui.control.Label
        uipanel5                        matlab.ui.container.Panel
        ColumnSelectorPanel             matlab.ui.container.Panel
        tblLabel                        matlab.ui.control.Label
        ExitImportCSV                   matlab.ui.control.Button
        ColumnScrollPanel               matlab.ui.container.Panel
        DoImportButton                  matlab.ui.control.Button
        OverrideData                    matlab.ui.control.CheckBox
        NumberOfBinsLabel               matlab.ui.control.Label
        ChangeBinAmount                 matlab.ui.control.Button
        editTitlesButton                matlab.ui.control.Button
        uipanel13                       matlab.ui.container.Panel
        histogramCheckBox               matlab.ui.control.CheckBox
        boxCheckBox                     matlab.ui.control.CheckBox
        text29                          matlab.ui.control.Label
        statsPositionMenu               matlab.ui.control.DropDown
        scatterCheckBox                 matlab.ui.control.CheckBox
        saveButton                      matlab.ui.control.Button
        clearPlotButton                 matlab.ui.control.Button
        mainAxes                        matlab.ui.control.UIAxes
    end

    %{
    %% Original Comments by Stephanie Yang

    MRE Data Reviewer - Written by Stephanie Yang on July 27, 2017
    Last updated: February 21, 2018
    
    This program utilizes a GUI to allow the user to plot and compare data from the
    SQLite databases, located at:/home/mrrc-all/MRE_LAB/MRE3_PROCESSING/. They can
    choose the plot type and parameters of interest to plot. The parameters are 
    pulled from the headers in the SQLite database.


    %% Comments by Jacob Weiner

    SQLite Explorer -  Created on June 27, 2025
    Last updated: July 24, 2025
    Based off code from Stephanie Yang
    Had to convert from GUIDE .m to .mlapp
    Allows users to choose open up SQLite databases, visualize them (including exporting graphs),
    run stastitical anayliss on them, and importing and exporting data from
    them.
    %}

    properties (Access = private)
        ColumnItems;
        SqliteFiles;
    end

    methods (Access = private)
                function ht = fix_xticklabels(app,handle,margins,textopts)
                % copied from https://github.com/metacoglab/RollwageDolanFleming/tree/master
                
                
                %FIX_XTICKLABELS will determine maximum allowed width
                %   of long XTickLabels and convert them into multiple line 
                %   format to fit maximum allowed width
                %
                %
                %   IN:
                %       handle      [optional] - axes handle (defaults to gca)
                %       margins     [optional] - relative margins width (defaults to 0.1)
                %       texopts     [optional] - cell array of addtitional text formating
                %                                options (defaults to {} )
                %
                %   OUT:
                %       ht - column vector of handles for created text objects
                %
                % This code was derrived from MY_XTICKLABELS by Pekka Kumpulainen 
                % http://www.mathworks.com/matlabcentral/fileexchange/19059-myxticklabels
                %   EXAMPLE:
                %       figure;
                %
                %       bar(rand(1,4));
                %       set(gca,'XTickLabel',{'Really very very long text', ...
                %                             'One more long long long text', ...
                %                             'Short one','Long long long text again'});
                %       fix_xticklabels();
                %
                %       figure;
                %
                %       bar(rand(1,4));
                %       set(gca,'XTickLabel',{'Really very very long text', ...
                %                             'One more long long long text', ...
                %                             'Short one','Long long long text again'});
                %       fix_xticklabels(gca,0.1,{'FontSize',16});
                %
                
                % Mikhail Erofeev 07.06.2013
                % Pekka Kumpulainen 12.2.2008
                
                if(~exist('handle','var'))
                    handle = gca;
                end
                
                if(~exist('textopts','var'))
                   textopts={};
                end
                if(~exist('margins','var'))
                    margins = 0.1;
                end
                
                xtickpos = get(handle,'XTick');
                xtickstring = get(handle,'XTickLabel');
                
                
                set(handle, 'XTickLabel','')
                h_olds = findobj(handle, 'Tag', 'MUXTL');
                if ~isempty(h_olds)
                    delete(h_olds)
                end
                
                %% Make XTickLabels 
                NTick = length(xtickpos);
                Ybot = min(get(handle,'YLim'));
                
                Xbot = get(handle,'XLim');
                max_w = ((max(Xbot) - min(Xbot) )/NTick)*(1-margins);
                
                
                ht = zeros(NTick,1);
                
                NLabels = length(xtickstring);
                
                for ii = 1:NTick
                    str_id = mod( ii - 1 , NLabels) + 1;
                    ht(ii) = text('String',xtickstring{str_id}, ...
                        'Units','data', ...
                        'VerticalAlignment', 'top', ...
                        'HorizontalAlignment', 'center ', ...
                        'Position',[xtickpos(ii) Ybot], ...
                        'Tag','MUXTL');
                end
                if ~isempty(textopts)
                    set(ht,textopts{:})
                end
                
                for ii = 1:NTick
                    auto_width(app,ht(ii),max_w);
                end


                %% squeeze axis if needed
                
                set(handle,'Units','pixels')
                Axpos = get(handle,'Position');
                % set(Hfig,'Units','pixels')
                % Figpos = get(Hfig,'Position');
                
                set(ht,'Units','pixels')
                TickExt = zeros(NTick,4);
                for ii = 1:NTick
                    TickExt(ii,:) = get(ht(ii),'Extent');
                end
                
                
                xlabh = get(handle,'XLabel');
                
                minbottom = min(TickExt(:,2));
                if( ~isempty(xlabh) )
                    oldu = get(xlabh,'Units');
                    set(xlabh,'Units','pixels');
                    ext = get(xlabh,'Extent');
                    pos = get(xlabh,'Position');
                    pos(2) = minbottom - ext(4);
                    set(xlabh,'Position',pos);
                    ext = get(xlabh,'Extent');
                    minbottom = ext(2);
                    set(xlabh,'Units',oldu)
                end
                
                needmove = -(Axpos(2) + minbottom);
                
                if needmove>0
                    Axpos(2) = Axpos(2)+needmove+2;
                    Axpos(4) = Axpos(4)-needmove+2;
                    set(handle,'Position',Axpos);
                end
                
                set(handle,'Units','normalized')
                set(ht,'Units','normalized')
                end
                
                function []=auto_width(~,h,maxw)
                    str = get(h,'string');
                    set(h,'string',{});
                    words = regexp(str,' ','split');
                    
                    
                    tail = words(2:end);
                    res = words(1);
                    ln = 1;
                    
                    while(~isempty(tail))
                        lin = res{ln};
                        lin_e = [lin ' ' tail{1}];
                        set(h,'string',lin_e);
                        ext = get(h,'Extent');
                        width=ext(3);
                        if(width>maxw)
                            ln =ln+1;
                            res{ln} = tail{1};
                        else
                            res{ln} = lin_e;
                        end
                        tail = tail(2:end);
                    end
                    set(h,'string',res);
                    ext = get(h,'Extent');
                    width=ext(3);
                    if(width>maxw)
                        warning('FIX_XTICKLABELS:over','Overflown box');
                    end
                end


         function wrapped = linewrap(~, str, maxLength)
            % Simple line wrapper for strings
            if iscell(str)
                str = str{1};
            end
            words = strsplit(str, ' ');
            wrapped = '';
            currentLine = '';
            
            for i = 1:length(words)
                if length(currentLine) + length(words{i}) + 1 <= maxLength
                    % Add word to the current line
                    if isempty(currentLine)
                        currentLine = words{i};
                    else
                        currentLine = [currentLine, ' ', words{i}];
                    end
                else
                    % Append the current line to the wrapped string
                    wrapped = sprintf('%s\n%s', wrapped, currentLine);
                    % Start a new line with the current word
                    currentLine = words{i};
                end
            end
            
            % Append the last line
            wrapped = sprintf('%s\n%s', wrapped, currentLine);
            wrapped = strtrim(wrapped);
        end


        function str = randomLetters10(~)
        %RANDOMLETTERS10  Generate a random 10-letter string (A–Z).
        %
        %   str = RANDOMLETTERS10() returns a 1×10 char array of random
        %   uppercase letters.
        
            letters = 'A':'Z';                 % all uppercase letters
            idx     = randi(numel(letters), 1, 10);  % 10 random indices
            str     = letters(idx);            % pick letters
        end

        function outStrings = launchFilterBuilder(~, handles, existing)
        % LAUNCHFILTERBUILDER – modal UI for selecting filter rows
        %
        % Returns a cell‑array of filter strings identical to the ones your
        % original ADD logic produced, or {} if the user cancels.
        
            %% ------------------------------------------------------------------
            %  1.  Build the list of *columns* and remember which are TEXT vs NUM
            % -------------------------------------------------------------------
            % a) TEXT columns that already have preset answers (groupParams)
            % Only include the text‑based filters (your groupParams)
            % a) TEXT columns that already have preset answers (groupParams)
                %–– TEXT filters from your existing groupParams ––%
         %–– Only include the TEXT‑based filters (your groupParams) ––%
        % 1) build raw list of all groupParams columns (table---column)
        rawCols = strcat(handles.groupParams(:,2), '---', extractBefore(handles.groupParams(:,1), ':'));
        
        % 2) unique‑ify to get the dropdown choices
        [colList, ~] = unique(rawCols, 'stable');
        
        % 3) detect which groupParams rows were slider‑based → numeric
        sliderMask = contains(handles.groupParams(:,1), 'NUM VALUE');
        numCols    = rawCols(sliderMask);
        
        % 4) default everything to TEXT, then flip the ones in numCols to NUM
        colTypes = repmat("TEXT", numel(colList), 1);
        isNum    = ismember(colList, numCols);
        colTypes(isNum) = "NUM";


            function rows = parseFilters(list, ~)
                if isempty(list) || isempty(list{1})
                    rows = {'' '' ''};
                    return;
                end
                n = numel(list);
                rows = cell(n,3);
                for ii = 1:n
                    % expect "Table---Col OP Value"
                    tkn = regexp( list{ii}, '(.+?)---(.+?) (>=|<=|~=|=|<|>) (.+)', 'tokens' );
                    if isempty(tkn)
                        rows(ii,:) = {'' '' ''};
                    else
                        tok = tkn{1};
                        rows(ii,:) = { [tok{1},'---',tok{2}], tok{3}, tok{4} };
                    end
                end
            end
        
            %% --------------------------------------------------------------
            %  2.  Create the modal figure with a uitable (three drop‑downs)
            % --------------------------------------------------------------
            fig = uifigure( ...
                   'Name'           , 'Filter Builder', ...
                   'Position'       , [100 100 820 350], ...
                   'WindowStyle'    , 'modal', ...
                   'CloseRequestFcn', @(~,~) doCancel());
        
            % uitable with 3 dropdown columns – start with one empty row
            tbl = uitable(fig, ...
                'Position'      , [20 80 780 250], ...
                'Data'          , parseFilters(existing, colList), ...
                'ColumnEditable', [true true true], ...
                'ColumnName'    , {'Column' 'Operator' 'Value'});
        
            % Column 1 – items are the combined “table---column” names
                tbl.ColumnFormat{1} = cellstr(colList).';

            % ─── Initialize dropdowns on every existing row ───
            for rr = 1:size(tbl.Data,1)
                if ~isempty(tbl.Data{rr,1})
                    % simulate a CellEdit on column 1 of row rr
                    syncRow([], struct('Indices',[rr,1]));
                end
            end
        
            % Column 2 + 3 will be supplied *per‑row* in response to user picks
            tbl.CellEditCallback = @syncRow;
        
            % Buttons
            uibutton(fig, 'Position',[20 20 90 40], 'Text','Add Row', ...
                           'ButtonPushedFcn',@(b,~) addRow());
            uibutton(fig, 'Position',[130 20 90 40], 'Text','Delete Row', ...
                           'ButtonPushedFcn',@(b,~) delRow());
            uibutton(fig, 'Position',[650 20 70 40], 'Text','Cancel', ...
                           'ButtonPushedFcn',@(b,~) doCancel());
            uibutton(fig, 'Position',[730 20 70 40], 'Text','Done', ...
                           'ButtonPushedFcn',@(b,~) doDone());
        
            uiwait(fig);      % ----------- block here until Done/Cancel --------
        
            if isgraphics(fig)      % user clicked Done
                outStrings = fig.UserData;
                delete(fig);
            else                     % dialog was closed another way
                outStrings = {};
            end
        
            %% =========  nested utilities  =====================================
            function syncRow(~, ev)
                r = ev.Indices(1);           % edited row
                cName = tbl.Data{r,1};       % full “table---column”
                if isempty(cName);  return;  end
        
                % Determine TEXT vs NUM
                idx     = find(strcmp(colList, cName), 1, 'first');
                isNum   = strcmp(colTypes(idx), "NUM");
        
                % Build Operator drop‑down
                if isNum
                    ops = {'=' '~=' '>=' '>' '<=' '<'};
                else
                    ops = {'=' '~='};
                end
                tbl.ColumnFormat{2} = ops;           % applies to *all* rows
                if ~ismember(tbl.Data{r,2}, ops)
                    tbl.Data{r,2} = ops{1};
                end
        
                % Build Value drop‑down (Column 3)  ------------------------------
                parts = split(cName, '---');      % {'table'  'column'}
                qTbl  = parts{1};  qCol = parts{2};
        
                if isNum
                    sql = sprintf('SELECT MIN(%s), MAX(%s) FROM %s WHERE %s IS NOT NULL', ...
                                   qCol, qCol, qTbl, qCol);
                    res = fetch(exec(handles.conn, sql));
                    lo  = floor(res.Data{1});
                    hi  = ceil (res.Data{2});
                    tbl.ColumnFormat{3} = arrayfun(@num2str, lo:hi, 'Uniform',false);
                else
                    sql = sprintf('SELECT DISTINCT %s FROM %s WHERE %s IS NOT NULL', ...
                                   qCol, qTbl, qCol);
                    res = fetch(exec(handles.conn, sql));
                    vals = sort(string(res.Data));
                    tbl.ColumnFormat{3} = cellstr(vals).';
                end
        
                if ~ismember(tbl.Data{r,3}, tbl.ColumnFormat{3})
                    tbl.Data{r,3} = tbl.ColumnFormat{3}{1};
                end
            end
        
            function addRow
                tbl.Data(end+1,:) = {'' '' ''};
            end
            function delRow
                if isempty(tbl.Selection); return; end
                tbl.Data(tbl.Selection(:,1),:) = [];
                if isempty(tbl.Data)
                    tbl.Data = {'' '' ''};
                end
            end
            function doDone
                d    = tbl.Data;
                rows = d(~cellfun(@isempty,d(:,1)),:);
                n    = size(rows,1);
                out  = cell(n,1);
                for k = 1:n 
                    bits    = split(rows{k,1}, '---');       % {table, col}
                    tblLbl  = bits{1};  colLbl = bits{2};
                    op      = rows{k,2};                     % now always preserved
                    val     = rows{k,3};
            
                    % build a single canonical form: "Table---Col OP Value"
                    out{k} = sprintf('%s---%s %s %s', tblLbl, colLbl, op, val);
                end
                fig.UserData = out;
                uiresume(fig);
            end
            function doCancel
                fig.UserData = {};  % return nothing
                uiresume(fig);
            end
        end

            
        function updateinclCategoriesList(app, handles, filter)

            if (app.OnlyIncludeSameTableFiltersCheckBox.Value == 1)
                %only get the filters that apply to the currenttly selected table
                mask = strcmp(handles.groupParams(:,2), filter );
                rmFreq = handles.groupParams(mask,:);
            else
                rmFreq = handles.groupParams;
            end
                rmFreq = strcat(rmFreq(:,2),'---', rmFreq(:,1));

                rmFreq = unique(rmFreq);
                set(handles.inclCategoriesList,'String',rmFreq);
                if ~isempty(handles.inclCategoriesList.String)
                    app.addCategoryButton.Enable = 'on';
                else
                    app.addCategoryButton.Enable = 'off';
                end
        end


        % simple inline ternary helper
        function out = pickAndChoose(~,cond, a, b)
            if cond
               out = a; 
            else 
                out = b; 
            end
        end

        function [intList, textList] = getAxisParams(~, tableName, handles) 
            %function to get data from SQLite database
            
            
            %get column names and column data types
            curs = exec(handles.conn,['PRAGMA table_info(',tableName,')']);
            curs = fetch(curs);
            intList = [];
            textList = [];
            
            for a = 1:size(curs.Data,1)
                if isempty(regexpi(curs.Data{a,2},'flag', 'once')) && strcmpi(curs.Data{a,2},'ID') ~= 1 && strcmpi(curs.Data{a,2},'Study_ID') ~= 1 && strcmpi(curs.Data{a,2},'visit') ~= 1
                    if strcmpi(curs.Data{a,3},'real') || strcmpi(curs.Data{a,3},'integer') || strcmpi(curs.Data{a,3},'int') == 1
                        intList{end+1} = [tableName,': ',curs.Data{a,2}]; %#ok<AGROW>
                    else
                        textList{end+1} = curs.Data{a,2}; %#ok<AGROW>
                    end
                end
            end
        end

        function tp = guessType(~,col)
            % Very simple type-guessing based on the MATLAB array:
            if isnumeric(col)
                tp = 'REAL';
            elseif isstring(col) || ischar(col) || iscellstr(col)
                tp = 'TEXT';
            else
                tp = 'BLOB';
            end
        end

        function lit = toLiteral(~,val)
                % Turn a single value into a safe SQL literal:
                if isnumeric(val)
                    if isnan(val)
                        lit = 'NULL';
                    else
                        lit = num2str(val);
                    end
                else
                    s = char(val);
                    s = strrep(s, '''', '''''');       % escape single quotes
                    lit = ['''' s ''''];               % wrap in quotes
                end
            end

        
        function handles = loadDbParameters(app, dbName, handles)
            %execute every time there's a database change


            app.xAxisParamList.Enable = 'on';
            app.yAxisParamList.Enable = 'on';
            app.xAxisParamList2.Enable = 'on';
            app.yAxisParamList2.Enable = 'on';
            app.logYCheckbox.Enable = 'on';
            app.y2GroupButton.Enable = 'on';
            app.markerColorDropdown.Enable = 'on';
            app.markerTypeDropdown.Enable = 'on';
            app.inclCategoriesList.Enable = 'on';
            app.narrowedList.Enable = 'on';
            app.plotButton.Enable = 'on';
            app.clearPlotButton.Enable = 'on';
            app.saveButton.Enable = 'on';
            app.clearNarrowButton.Enable  = 'on';
            app.statsPositionMenu.Enable = 'on';
            app.ignoreZerosCheckbox.Enable = 'on';
            app.boxCheckBox.Enable = 'on';
            app.scatterCheckBox.Enable = 'on';
            app.plotTypeDropdown.Enable = 'on';
            app.CSVImportButton.Enable = 'on';
            app.ExportDatatoCSV.Enable = 'on';
            app.RevertChanges.Enable = 'on';
            
            %close previous connection if it exists
            if isfield(handles,'conn') && isopen(handles.conn)
                close(handles.conn);
            end


            %connect to new SQLite Database
            handles.databasePath = ['jdbc:sqlite:', dbName];
            handles.conn = database('','','','org.sqlite.JDBC',handles.databasePath);
            
            %get table names

            curs = exec(handles.conn, 'SELECT name FROM sqlite_master WHERE type="table"');
            curs = fetch(curs);
            handles.tableInfo = sort(curs.Data);

            % Old code from when tableInfo used to start as name and type
            %deleteRow = [];
            %for i = 1:size(tableInfo,1)
             %   if strcmpi(tableInfo{i,2},'TABLE') ~= 1
              %      deleteRow{end+1} = i;
               % end
            %end
            %for j=length(deleteRow):-1:1
             %   tableInfo(deleteRow{j},:) = [];
            %end
            %handles.tableInfo = sort(tableInfo(:,1));
            
            %cell of headers
            handles.axisParams = [];
            handles.groupParams = [];
            waitbarHandle = waitbar(0,'Loading parameters from database...');
            for k = 1:length(handles.tableInfo)
                %x/y axis list
                [intHeaders,textHeaders] = getAxisParams(app, handles.tableInfo{k},handles);
                handles.axisParams = horzcat(handles.axisParams,intHeaders);
            
                %group list
                if ~isempty(textHeaders)
                    selTexts = strjoin(textHeaders,', ');
                    cursGroup = fetch(exec(handles.conn, ['select ',selTexts,' from ',handles.tableInfo{k}]));
                    rawData   = cursGroup.Data;
                    % if fetch returned a numeric array, wrap into a cell array first
                    if ~iscell(rawData)
                        rawData = num2cell(rawData);
                    end
                    % now convert every element (whether char, numeric, etc.) to string
                    groupData = cellfun(@num2str, rawData, 'UniformOutput', false);
            
                    %check each column of the table
                    for l = 1:size(groupData,2)
                        %get unique responses
                        uniqueRes = unique(groupData(:,l));
                        uniqueRes = uniqueRes(~cellfun('isempty',uniqueRes));
            
                        %save headers if has preset answers
                        if length(uniqueRes) <= 7 %length of headache column has 7 unique preset answers
                            uniqueRes = strcat(textHeaders{l},{': '},uniqueRes);
                            uniqueRes = horzcat(uniqueRes,repmat(handles.tableInfo(k),length(uniqueRes),1)); %#ok<AGROW> %keep track of what table the headers are coming from
                            handles.groupParams = vertcat(handles.groupParams,uniqueRes);
                        end
                    end
                end
                waitbar(k/length(handles.tableInfo));
            end
            close(waitbarHandle)
            
            
            %populate lists with database headers
            groupList = regexp(handles.axisParams',': ','split');
            groupList = vertcat(groupList{:});
            getType = groupList(:,2);
            
            set(handles.xAxisParamList,'String',handles.tableInfo);
            set(handles.yAxisParamList,'String',handles.tableInfo);
            set(handles.xAxisParamList,'Value',1);
            set(handles.yAxisParamList2,'Value',1);
            
            defaultTable = handles.xAxisParamList.String{1};

            handles.defaultList2 = getType( ~contains(groupList(:,1),defaultTable) );

            set(handles.xAxisParamList2,'Value',1);

            rmFreq = handles.defaultList2;

            rmFreq = unique(rmFreq);

            txt = app.YAxisParametersFilterEditField.Value;
            if (~isempty(txt))
                idx    = contains(rmFreq, txt, 'IgnoreCase', true);
                rmFreq =  rmFreq(idx);
            end


            set(handles.xAxisParamList2,'String',rmFreq);

            set(handles.yAxisParamList2,'Value',1);


            set(handles.yAxisParamList2,'String',rmFreq);


            if ~isempty(handles.groupParams)
                updateinclCategoriesList(app,handles,handles.tableInfo(1));
            end
            
            %initialize parameters
            handles.legend = [];
            handles.histLegend = {};
            handles.boxCats = [];
            handles.boxVals = [];
            handles.titleCats = [];
            handles.boxN = [];
            handles.savePlot = [];
            handles.xAxisParamList.Value = 1;
            handles.yAxisParamList.Value = 1;
            ycomb = {handles.yAxisParamList2.String{1}};
            handles.yType = [handles.yAxisParamList.String{1},': ',strjoin(ycomb(~cellfun(@isempty,ycomb)),'_')];
            xcomb = {handles.xAxisParamList2.String{1}};
            handles.xType = [handles.xAxisParamList.String{1},': ',strjoin(xcomb(~cellfun(@isempty,xcomb)),'_')];
            handles.selectedCategory = [];
            handles.savePath = [];
            handles.mdl = [];
            handles.pVal = [];
            handles.plotmdl = [];
            handles.r2Text = [];
            handles.pText = [];
            handles.numText = [];
            
            %set default values for listboxes and pop-ups
            set(handles.mainAxes,'FontSize',12);
            handles.plotTypeDropdown.Value = 1;
            handles.plotType = handles.plotTypeDropdown.String{1};
            handles.markerColorDropdown.Value = 1;
            handles.markerColor = handles.markerColorDropdown.String{1};
            handles.markerTypeDropdown.Value = 1;
            handles.markerType = handles.markerTypeDropdown.String{1};
            handles.inclCategoriesList.Value = 1;
            set(handles.boxCheckBox,'Value',0);
            set(handles.scatterCheckBox,'Value',0);
            set(handles.narrowedList,'String',{''});
            set(handles.narrowedList, 'min', 0, 'max', 2);
            set(handles.narrowedList,'Value',[]);
            set(handles.boxCheckBox,'visible','off');
            set(handles.scatterCheckBox,'visible','on');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('SQLite_Explorer.mat');
            
            % Set component properties that require runtime configuration
            app.mainAxes.UserData = componentData.mainAxes.UserData;
          %  app.inclCategoriesList.scroll(app.inclCategoriesList.Items{4});

            app.uipushtool2.ClickedCallback = 'filemenufcn(gcbf,''FileSave'')';
       % app.uipushtool2.ClickedCallback =    @(src,event)uisave('src', 'event');


          %  app.yAxisParamList.scroll(app.yAxisParamList.Items{11});
           % app.yAxisParamList2.scroll(app.yAxisParamList2.Items{241});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MREDataReviewer_OpeningFcn(app, varargin)
            % --- Executes just before MREDataReviewer is made visible.

            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            guidata(hObject, handles);


            temp = fileread('config.json');

            config = jsondecode( temp);


                handles.config = config;
            
                % 2a) List all .sqlite in the main directory

                d = dir(fullfile(config.sqliteDir, '*.sqlite'));
                mainNames = { d.name }';                              % e.g. {'one.sqlite'; 'two.sqlite'; …}
                mainPaths = fullfile(config.sqliteDir, mainNames);    % full paths to each
            

                % 2b) Pull in your extra files from the JSON
                    extraPaths = config.additionalSqliteFiles(:);     % cell-array of full paths
                if (~isempty(extraPaths))
                    % extract just the file names for display
                    [~, extraBase, ext] = cellfun(@fileparts, extraPaths, 'UniformOutput', false);
                    extraNames = strcat(extraBase, ext);              % e.g. {'alpha.sqlite'; 'beta.sqlite'; …}
                else
                    extraPaths = {}; extraNames = {};
                end
            
                % 2c) Combine them
                allNames = [ mainNames; extraNames ];
                allPaths = [ mainPaths; extraPaths ];
            
                % 2d) Sort by the display name
                [allNames, sortIdx] = sort(allNames);
                allPaths = allPaths(sortIdx);


                handles.dbDefaultMessage = '(No Database Selected)';

            
                % 2e) Stick “(No Database Selected)” on top
                displayList = [handles.dbDefaultMessage; allNames];
            
                % 2f) Save the full paths somewhere so when the user picks index n,
                %     you know exactly which full-path that was:
                handles.sqlitePaths = ['null';allPaths];   % first is blank for “none”
                
                % 2g) Update your dropdown (popupmenu) …
                handles.selectDbMenu.String = displayList;


            %load .jar file for sqlite compatibility
            javaaddpath(handles.config.jdbcPath);

            %javaaddpath('/home/mrrc-all/MRE_LAB/SLITVENTS/sqlite-jdbc.jar')
            
            %cell of headers- hopefully this will fix some problems.
            handles.axisParams = [];
            handles.groupParams = [];
            handles.filesOpenedTable = table(); 


            %turn off visibility of tick marks and clear parameter lists
            axes(handles.mainAxes)
            set(handles.mainAxes,'FontSize',12);
            set(handles.boxCheckBox,'visible','off');
            set(handles.scatterCheckBox,'visible','on');
            set(handles.xAxisParamList,'String',{''});
            set(handles.yAxisParamList,'String',{''});
            set(handles.xAxisParamList2,'String',{''});
            set(handles.yAxisParamList2,'String',{''});
            set(handles.inclCategoriesList,'String',{''});
            set(handles.narrowedList,'String',{''});
            set(handles.selDbPanel,'BorderWidth',3);

            cla reset;

            handles.NameLabel = app.NameLabel.Text;

            handles.logFile = fopen('log/log.txt', 'a');


            handles.nBins = 10;  
            set(app.NumberOfBinsLabel, 'Text', sprintf('Number of Bins: %d', handles.nBins));


            handles.histLegend = {};   % will hold your “n = …” entries
            handles.histEdges  = [];
            handles.histXLim = [];   % will store the fixed x-limits
            handles.histXTick = [];   % will store the fixed tick locations
            handles.histXTickLabel = {};   % will store the fixed tick labels
            

            app.mainAxes.InteractionOptions.LimitsDimensions = "xy";
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: addCategoryButton
        function addCategoryButton_Callback(app, event)
            % Create GUIDE‑style callback args
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
        
            % 1) Launch the filter‑builder and wait for user
                % 1a) Grab whatever is already in "Narrowed by:"
                current = handles.narrowedList.String;
            
                % 1b) Launch the filter‑builder, supplying the existing rows
                newFilters = launchFilterBuilder(app, handles, current);
        
            % 2) If user pressed Cancel, do nothing
            if isempty(newFilters);  return;  end
        
            % 3) Refresh the “Narrowed By” list so it looks EXACTLY as before
            set(handles.narrowedList , 'String', newFilters(:));
            set(handles.narrowedList , 'Value' , []);   % clear any highlight
            set(handles.narrowedList , 'min'   , 0, 'max', 2);
        
            guidata(hObject, handles);
        end

        % Value changed function: boxCheckBox
        function boxCheckBox_Callback(app, event)
            % --- Executes on button press in clearPlotButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clearPlotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            x = xlim(handles.mainAxes);
            y = ylim(handles.mainAxes);
            xpos = (x(2)-x(1))*0.03+x(1);
            ypos = (y(2)-y(1))*0.97+y(1);

            getXTicks = findobj(handles.mainAxes,'Tag','MUXTL');
            
            %get number of subjects
            groupStr = [];
            for a = 1:numel(handles.boxN)
                if(iscell(getXTicks(a).String))
                    groupNum = ['n ( ',getXTicks(a).String{1},') = '];
                else
                    groupNum = ['n ( ',getXTicks(a).String,') = '];
                end

                if isempty(groupStr)
                    groupStr=sprintf('%s%s',groupNum,handles.boxN{a});
                else
                    groupStr=sprintf('%s\n%s%s',groupStr,groupNum,handles.boxN{a});
                end
            end
            
            volStr = [];
            
            
            axes(handles.mainAxes)
            if ~isempty(handles.pVal)
                %display textbox with p-value
                pStr = handles.pVal;
                pStr=sprintf('%s\n%s%s',pStr,groupStr,volStr);
                if get(handles.boxCheckBox,'Value') == 1
                    handles.pText = text(xpos,ypos,pStr,'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                    pos = handles.statsPositionMenu.String{handles.statsPositionMenu.Value};
                    if strcmp(pos,'Bottom')
                        %xpos = (x(2)-x(1))*0.97-handles.pText.Extent(3)+x(1);
                        ypos = (y(2)-y(1))*0.03+handles.pText.Extent(4)+y(1);
                        delete(handles.pText);
                        handles.pText = text(xpos,ypos,pStr,'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                    end
                else
                	delete(handles.pText);
                end
            else
                %if there is no p-value data, remove existing legends/textboxes and
                %create a textbox with just pixel values/group sizes
                if ~isempty(handles.pText)
                    delete(handles.pText);
                end
                if get(handles.boxCheckBox,'Value') == 1 && strcmp(handles.plotType,'Box Plot')==1
                    pStr = sprintf('%s%s',groupStr,volStr);
                    handles.numText = text(xpos,ypos,pStr,'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                    pos = handles.statsPositionMenu.String{handles.statsPositionMenu.Value};
                    if strcmp(pos,'Bottom')
                        %xpos = (x(2)-x(1))*0.97-handles.numText.Extent(3)+x(1);
                        ypos = (y(2)-y(1))*0.03+handles.numText.Extent(4)+y(1);
                        delete(handles.numText);
                        handles.numText = text(xpos,ypos,pStr,'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                    end
                else
                    if ~isempty(handles.numText)
                        delete(handles.numText);
                    end
                end
            end
            
            guidata(hObject,handles);
        end

        % Button pushed function: clearNarrowButton
        function clearNarrowButton_Callback(app, event)
            % --- Executes on button press on clearNarrowButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clearNarrowButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %clear list under "Narrowed By"
            set(handles.narrowedList,'Value',[]);
            set(handles.narrowedList,'String',{''});
            set(handles.narrowedList, 'min', 0, 'max', 2);
            handles.selectedCategory = [];
            guidata(hObject,handles);
        end

        % Button pushed function: clearPlotButton
        function clearPlotButton_Callback(app, event)
            % --- Executes on button press in clearPlotButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clearPlotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %clear axes
            axes(handles.mainAxes);
            title(handles.mainAxes,'')
            ylabel(handles.mainAxes, '')
            xlabel(handles.mainAxes,'')
            hold(handles.mainAxes,'off');
            
            if strcmp(handles.plotType,'Box Plot')==1
                set(handles.xAxisParamList,'String',{'N/A'});
                set(handles.xAxisParamList2,'String',{''});
                set(handles.xAxisParamList2,'Value',1);
            end
            if ~isempty(handles.groupParams)
                %set(handles.inclCategoriesList,'String',handles.groupParams(:,1));
                %getLoc = strcmp(handles.groupParams(:,1),getCat);
                %set(handles.inclCategoriesList,'Value',find(getLoc == 1));
                updateinclCategoriesList(app,handles, handles.yAxisParamList.String{handles.yAxisParamList.Value} );
            end
            
            ycomb = {handles.yAxisParamList2.String{1}};
            handles.yType = [handles.yAxisParamList.String{1},': ',strjoin(ycomb(~cellfun(@isempty,ycomb)),'_')];
            handles.legend = [];
            handles.histLegend = {};
            handles.histEdges  = [];
            handles.histData = {};
            handles.histCols = {};
            legend(handles.mainAxes,'off');
            handles.pVal = [];
            handles.mdl = [];
            handles.plotmdl = [];
            set(handles.boxCheckBox,'Value',0);
            set(handles.scatterCheckBox,'Value',0);
            handles.r2Text = [];
            handles.pText = [];
            handles.numText = [];
            
            handles.boxCats = [];
            handles.boxVals = [];
            handles.titleCats = [];
            handles.boxN = [];


            handles.xLabelGraphText = [];
            handles.yLabelGraphText = [];

            handles.savePlot = [];
           % set(handles.narrowedList,'String',{''});
           % set(handles.narrowedList, 'min', 0, 'max', 2);
           % set(handles.narrowedList,'Value',[]);
           % set(handles.logXCheckbox,'Value',0);
           % set(handles.logYCheckbox,'Value',0);
            %set(handles.ignoreZerosCheckbox,'Value',0);
            
            cla reset;
            set(handles.mainAxes,'FontSize',12);

            app.editTitlesButton.Enable = 'off';

            guidata(hObject,handles);
        end

        % Button pushed function: editTitlesButton
        function editTitlesButton_Callback(app, event)
            % --- Executes on button press in editTitleButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editTitleButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isempty(handles.mainAxes.Children)
                errordlg('There is no data plotted.','Edit Error');
            end
            
            if ~iscell(handles.mainAxes.Title.String)
                handles.mainAxes.Title.String = {handles.mainAxes.Title.String};
            end

            %get current title and axes labels
            if length(handles.mainAxes.Title.String) > 1
                getTitle = strjoin(handles.mainAxes.Title.String,{' '});
            else
                getTitle = handles.mainAxes.Title.String{1};
            end
            getX = handles.mainAxes.XLabel.String;
            getY = handles.mainAxes.YLabel.String;
            
            %create input dialog box

            getXTicks = findobj(handles.mainAxes,'Tag','MUXTL');
            N = numel(getXTicks);
           prompt    = cell(1, 3 + N);
            defaultans = cell(1, 3 + N);
            prompt(1:3) = {'Title:','XLabel:','YLabel:'};
            dlg_title = 'Edit Title/Labels';
            num_lines = 1;
            defaultans(1:3) = {getTitle,getX,getY};
            
            %add option to edit xtick labels if boxplot
            if strcmp(handles.plotType,'Box Plot') == 1

                for k = 1:N
                    idx = 3 + k;                      % where to put the k-th group
                    prompt{idx} = sprintf('Group%d:', k);
                
                    s = getXTicks(N-k+1).String;     % match your original reverse order
                    if iscell(s) && numel(s)>1
                        defaultans{idx} = strjoin(s,' ');
                    else
                        % if it was a single char-array, make it into a 1×1 cell
                        defaultans{idx} = s{1};
                    end
                end
            end
            answer = inputdlg(prompt, dlg_title, num_lines, defaultans);
            
            %set new titles/labels
            if ~isempty(answer)
                handles.mainAxes.Title.String = linewrap(app,strrep(strrep(answer{1},'\_','_'),'_','\_'),50);
                handles.mainAxes.XLabel.String = strrep(strrep(answer{2},'\_','_'),'_','\_');
                handles.mainAxes.YLabel.String = strrep(strrep(answer{3},'\_','_'),'_','\_');
                handles.mainAxes.Title.Interpreter = 'tex';
                handles.mainAxes.XLabel.Interpreter = 'tex';
                handles.mainAxes.YLabel.Interpreter = 'tex';
            
                if strcmp(handles.plotType,'Box Plot') == 1
                    getXTicks = findobj(handles.mainAxes,'Tag','MUXTL');
                    for i = 1:length(answer)-3
                        lwLength = length(getXTicks(length(getXTicks)-i+1).String{1});
                        getXTicks(length(getXTicks)-i+1).String = {linewrap(app,answer{i+3},lwLength)};
                    end
                end
            end


            guidata(hObject,handles);
        end

        % Value changed function: inclCategoriesList
        function inclCategoriesList_Callback(app, event)
            % --- Executes on selection change in inclCategoriesList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to inclCategoriesList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns inclCategoriesList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from inclCategoriesList
            
            %determine if slider needs to be used & set slider limits

            newGroupParms = strcat(handles.groupParams(:,2),'---', handles.groupParams(:,1));

            getLoc = strcmp(newGroupParms,handles.inclCategoriesList.String{handles.inclCategoriesList.Value});
            handles.selectedCategory = handles.groupParams(getLoc(:,1),:);
            
            guidata(hObject,handles);
        end

        % Value changed function: markerColorDropdown
        function markerColorDropdown_Callback(app, event)
            % --- Executes on selection change in markerColorDropdown.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to markerColorDropdown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns markerColorDropdown contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from markerColorDropdown
            
            %set marker color for plot
            colStr = get(hObject,'String');
            colVal = get(hObject,'Value');
            switch colStr{colVal}
                case 'Red'
                    handles.markerColor = 'r';
                case 'Yellow'
                    handles.markerColor = 'y';
                case 'Magenta'
                    handles.markerColor = 'm';
                case 'Cyan'
                    handles.markerColor = 'c';
                case 'Green'
                    handles.markerColor = 'g';
                case 'Blue'
                    handles.markerColor = 'b';
                case 'Black'
                    handles.markerColor = 'k';
            end
            guidata(hObject,handles);
        end

        % Value changed function: markerTypeDropdown
        function markerTypeDropdown_Callback(app, event)
            % --- Executes on selection change in markerTypeDropdown.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to markerTypeDropdown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns markerTypeDropdown contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from markerTypeDropdown
            
            %set marker type for plot
            typeStr = get(hObject,'String');
            typeVal = get(hObject,'Value');
            switch typeStr{typeVal}
                case 'o'
                    handles.markerType = 'o';
                case '+'
                    handles.markerType = '+';
                case '*'
                    handles.markerType = '*';
                case '.'
                    handles.markerType = '.';
                case 'x'
                    handles.markerType = 'x';
                case 'square'
                    handles.markerType = 's';
                case 'diamond'
                    handles.markerType = 'd';
                case 'star'
                    handles.markerType = 'p';
                case 'hexagram'
                    handles.markerType = 'h';
            end
            guidata(hObject,handles);
        end

        % Button pushed function: plotButton
        function plotButton_Callback(app, event)
            % --- Executes on button press in plotButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to plotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            

            if ( strcmp(handles.plotType,handles.previousPlotType)==0 )
                clearPlotButton_Callback(app,event);
            end

            handles.previousPlotType = handles.plotType;

            axes(handles.mainAxes);
            hold(handles.mainAxes,'on');
            set(handles.mainAxes,'FontSize',12);
            
            %get x and y axis selection - if frequencies exist, join column name and
            %frequency with an underscore to get the proper column name from the
            %database; write column, ID, & visit in SQLite query format
            % ---------- Y-axis: allow multi-select → row-average ---------------------
            ySelIdx = handles.yAxisParamList2.Value;                       % can be vector
            tableY  = handles.yAxisParamList.String{handles.yAxisParamList.Value};
            yCols   = handles.yAxisParamList2.String(ySelIdx);
            qualifiedY = cellfun(@(c)[tableY,'.',c],yCols,'uni',0);
            
            if numel(yCols) > 1
                if app.YesSumColumnNoAverageColumnCheckBox.Value == 1
                    % If checkbox is checked "Yes", plot sum
                    colYExpr   = sprintf('(%s)', strjoin(qualifiedY,' + '));
                    colYAlias  = ['sum_', strjoin(yCols,'_')];
                    colYLabel  = [strjoin(yCols,' + '),' (sum)'];
                else
                    % Default to average
                    colYExpr   = sprintf('(%s)/%d', strjoin(qualifiedY,' + '), numel(yCols));
                    colYAlias  = ['avg_', strjoin(yCols,'_')];
                    colYLabel  = [strjoin(yCols,' + '),' (avg)'];
                end
                colYSelect = [colYExpr,' AS ',colYAlias];
                yNullCheck = strjoin(cellfun(@(c)[c,' IS NOT NULL'],qualifiedY,'uni',0),' AND ');

            else
                colYAlias  = yCols{1};
                colYSelect = qualifiedY{1};
                yNullCheck = [qualifiedY{1},' IS NOT NULL'];
                colYLabel  = colYAlias;
            end
            
            handles.yType = [tableY,': ',colYLabel];
            colY          = colYAlias;     % keep variable name the rest of the file expects


            % ensure tableY has a .visit column ──
            % check for 'visit' in tableY schema
            pragmaCmd = sprintf('PRAGMA table_info(%s)', tableY);
            infoY     = fetch(exec(handles.conn, pragmaCmd));
            colNamesY = infoY.Data(:,2);  % second column is the column name
            if ~any(strcmp(colNamesY,'visit'))
                alterCmd = sprintf('ALTER TABLE %s ADD COLUMN visit INTEGER DEFAULT 1', tableY);
                exec(handles.conn, alterCmd);
            end


            handles.yType = [tableY,': ',colY];
            yID = [tableY,'.ID'];
            yVis = [tableY,'.visit'];
            if strcmp(handles.plotType,'Scatter Plot')==1
                    % ---------- X-axis: allow multi-select → row-average ---------------------
                    xSelIdx  = handles.xAxisParamList2.Value;
                    tableX   = handles.xAxisParamList.String{handles.xAxisParamList.Value};
                    xCols    = handles.xAxisParamList2.String(xSelIdx);
                    qualifiedX = cellfun(@(c)[tableX,'.',c],xCols,'uni',0);
                    
                    if numel(xCols) > 1
                        colXExpr   = sprintf('(%s)/%d', strjoin(qualifiedX,' + '), numel(xCols));
                        colXAlias  = ['avg_', strjoin(xCols,'_')];
                        colXSelect = [colXExpr,' AS ',colXAlias];
                        xNullCheck = strjoin(cellfun(@(c)[c,' IS NOT NULL'],qualifiedX,'uni',0),' AND ');
                        colXLabel  = [strjoin(xCols,' + '),' (avg)'];
                    else
                        colXAlias  = xCols{1};
                        colXSelect = qualifiedX{1};
                        xNullCheck = [qualifiedX{1},' IS NOT NULL'];
                        colXLabel  = colXAlias;
                    end
                    
                    handles.xType = [tableX,': ',colXLabel];
                    xID           = [tableX,'.ID'];
                    xVis          = [tableX,'.visit'];

            end
            
            %initialize column commands
            commCond = []; %command conditions - joining tables by identifier
            commEnd = []; %command ending - eliminating rows outside categories of interest
            
            %get table, columns, types for selected group by finding the location of
            %the group in the groupParams list (contains the table names for the
            %columns populating the "Only Include" List
            if ~isempty(handles.narrowedList.String{1})
            
                %initialize
                numCons = numel(handles.narrowedList.String);
                selTable = cell(1,numel(numCons));
                selCol = selTable;
                selCat = selTable;

                %— directly parse each filter string “Table---Col OP Value” —%
                    for i = 1:numCons
                        tok = regexp( ...
                            handles.narrowedList.String{i}, ...
                            '(.+?)---(.+?)\s*(<=|>=|~=|=|<|>)\s*(.+)', ...
                            'tokens','once' ...
                        );
                        if isempty(tok)
                            continue;   % skip any malformed entry
                        end
                
                        % unpack
                        tbl       = tok{1};
                        col       = tok{2};
                        %op        = tok{3};   % if you need it later, you now have it in tok{3}
                        val       = tok{4};
                
                        selTable{i} = tbl;
                        selCol{i}   = col;
                        selCat{i}   = val;
                
                        % if you really need handles.selectedCategory for downstream code:
                        handles.selectedCategory{i} = { col, tbl };
                    end
            
                %if category is from different table as parameter plotted
                getTables = unique(selTable);
                for h = 1:length(getTables)
                    tbl = getTables{h};
                
                    % build a dedup-subquery: only the MIN(rowid) per ID
                    dedupTbl = sprintf([ ...
                        '(SELECT * FROM %s ' ...
                        ' WHERE rowid IN ( ' ...
                        '     SELECT MIN(rowid) FROM %s GROUP BY ID' ...
                        ' ) ' ...
                        ') AS %s'], ...
                        tbl, tbl, tbl);
                
                    if strcmp(handles.plotType,'Scatter Plot')
                        if ~strcmp(tbl, tableX) && ~strcmp(tbl, tableY)
                            commCond = [
                                ' INNER JOIN ' dedupTbl ...
                                ' ON ' tbl '.ID = ' xID ];
                            % … (leave your visit-flag lines here unchanged) …
                        end
                
                    elseif strcmp(handles.plotType,'Box Plot')
                        if ~strcmp(tbl, tableY)
                            commCond = [
                                ' INNER JOIN ' dedupTbl ...
                                ' ON ' tbl '.ID = ' yID ];
                            % … (leave your visit-flag lines here unchanged) …
                        end
                
                    elseif strcmp(handles.plotType,'Histogram')
                        if ~strcmp(tbl, tableY)
                            commCond = [
                                ' INNER JOIN ' dedupTbl ...
                                ' ON ' tbl '.ID = ' yID ];
                        end
                    end
                end
            
                for i = 1:length(selCat)
                    %if relative to slider
                    if ~isempty(regexpi(selCat{i},'Slider Value', 'once'))
                        if isempty(regexpi(selCol{i},'shuntrevs', 'once'))
                            if ~isempty(find(selCat{i}=='>', 1))
                                getNum = strsplit(handles.narrowedList.String{i},'>');
                                selCat{i} = ['>',getNum{end}];
                                commEnd = [commEnd,' AND ',selTable{i},'.',selCol{i},selCat{i}]; %#ok<AGROW>
                            else
                                getNum = strsplit(handles.narrowedList.String{i},char(8804));
                                selCat{i} = ['<=',getNum{end}];
                                commEnd = [commEnd,' AND ',selTable{i},'.',selCol{i},selCat{i}]; %#ok<AGROW>
                            end
                        else %if shuntrevs, translate text to numbers for slider
                            if ~isempty(find(selCat{i}=='>', 1))
                                commEnd = [commEnd,' AND (shunthx.shuntrevs ==''3plus''']; %#ok<AGROW>
                                numRevs = [];
                                getNum = strsplit(handles.narrowedList.String{i},'>');
                                if str2double(getNum{end})<3
                                    numRevs = str2double(getNum{end})+1:3;
                                end
                                for r = 1:length(numRevs)
                                    commEnd = [commEnd,' OR shunthx.shuntrevs ==''',num2str(numRevs(r)),'''']; %#ok<AGROW>
                                end
                                commEnd = [commEnd,')']; %#ok<AGROW>
                            else
                                commEnd = [commEnd,' AND (shunthx.shuntrevs ==''0''']; %#ok<AGROW>
                                getNum = strsplit(handles.narrowedList.String{i},char(8804));
                                selCat{i} = ['<=',getNum{end}];
                                numRevs = 1:str2double(getNum{end});
                                for r = 1:length(numRevs)
                                    commEnd = [commEnd,' OR shunthx.shuntrevs ==''',num2str(numRevs(r)),'''']; %#ok<AGROW>
                                end
                                commEnd = [commEnd,')']; %#ok<AGROW>
                            end
                        end
                    else
                        % --- begin operator‑aware filter assembly ---
                        % tokens = { table, column, operator, rawValue }
                        tok = regexp( handles.narrowedList.String{i}, ...
                                      '(.+?)---(.+?)\s*(<=|>=|~=|=|<|>)\s*(.+)', ...
                                      'tokens','once' );
                        if isempty(tok)
                            continue;  % malformed filter string—skip
                        end
                        tbl = tok{1};   col = tok{2};
                        op  = tok{3};   v   = tok{4};
                
                        % convert MATLAB '~=' into SQL '<>'
                        if strcmp(op,'~=')
                            sqlOp = '<>';
                        else
                            sqlOp = op;
                        end
                
                        % add quotes only around non‑numeric values
                        if all(isstrprop(v,'digit'))
                            valExpr = v;
                        else
                            valExpr = ['''' v ''''];
                        end
                
                        % append to WHERE clause
                        commEnd = [ commEnd, ...
                                    ' AND ', tbl, '.', col, ' ', sqlOp, ' ', valExpr ]; %#ok<AGROW>
                        % --- end operator‑aware filter assembly ---
                    end
                end
            
            else
                selCat = 'All Groups';
            end


            %code to select ID and visit & sort so that first unflagged visit is picked
            matchVis = [];
            if 1 == 2 &&   ( strcmp(tableY,'Demographics')==0 && (exist('tableX','var') && strcmp(tableX,'Demographics')==0) )
            
                %demographics doesn't have a visit column
                getIDvis = [', ',yID,', ',yVis];
                sortBy = [' order by ',yID,' asc, ',yVis,' asc'];
            
                if exist('tableX','var') && strcmp(tableY,tableX) == 0
                    %pulling from different tables
                    matchVis = [' AND ',yVis,' = ',xVis];
                end
            else
                getIDvis = [', ',yID]; %only get ID/sort by ID when choosing from Demographics table
                sortBy = [' order by ',yID,' asc'];
            end
            
            %join fragments to generate full query command
            if strcmp(handles.plotType,'Scatter Plot')==1
                if strcmp(tableY,tableX) == 0 %pulling from different tables
                    comm = ['SELECT ',colXSelect,', ',colYSelect,getIDvis,' FROM ',tableX,...
                            ' INNER JOIN ',tableY,' ON ',yID,' = ',xID,matchVis,' ',commCond,...
                            ' WHERE ',xNullCheck,' AND ',yNullCheck,commEnd,sortBy];
                else %select columns from same table
                    comm = ['SELECT ',colXSelect,', ',colYSelect,getIDvis,' FROM ',tableX,' ',commCond,...
                            ' WHERE ',xNullCheck,' AND ',yNullCheck,commEnd,sortBy];
                end
            elseif strcmp(handles.plotType,'Box Plot') == 1
                visitCom = [' AND IFNULL(',tableY,'.visit, 1) = 1 '];
                comm = ['SELECT ',colYSelect,getIDvis,' FROM ',tableY,commCond,...
                        ' WHERE ',yNullCheck,visitCom,commEnd,sortBy];           
            elseif strcmp(handles.plotType,'Histogram')==1
                        % --- pull just one column of data from tableY.colY ---
                 visitCom = [' AND IFNULL(',tableY,'.visit, 1) = 1 '];

                comm = ['SELECT ',colYSelect,', ',tableY,'.ID FROM ',tableY,...
                   commCond,' WHERE ',yNullCheck,visitCom,commEnd,sortBy];
            end
            
            %pull data from database
            curs = fetch(exec(handles.conn,comm));
            close(curs);
            curs = fetch(exec(handles.conn,comm));
            plotData = curs.Data;
            disp('SQL Query:');
            disp(curs.SQLQuery);

            
            %plot on axes based on plotType specified in dropdown
            if iscell(plotData) == 0
                warning('No data found.');
                disp('SQL Query:');
                disp(curs.SQLQuery);
                disp('SQL Error Message:');
                disp(curs.Message);
              
            else
                if strcmp(plotData{1},'No Data')==1
                    warning('No data found.');
                else
                    % figure out which column holds the ID
                    switch handles.plotType
                        case 'Scatter Plot'
                            idCol = 3;           % [x y ID visit …]
                        case 'Box Plot'
                            idCol = 2;           % [value ID visit …]
                        case 'Histogram'
                            idCol = 2;           % [value ID visit …]
                        otherwise
                            idCol = [];
                    end
            
                    if ~isempty(idCol) && size(plotData,2) >= idCol


                        firstElement = plotData(1,idCol);
                        if (isnumeric(firstElement{1} ) )
                            ids = cell2mat(plotData(:,idCol));
                        else
                          ids = char(plotData(:,idCol));
                        end
                        if size(ids,1) ~= size(unique(ids,'rows'),1)
                                uialert( ...
                                  app.figure1, ...
                                  sprintf('Table ''%s'' contains duplicate IDs.', tableY), ...
                                  'Duplicate IDs Detected', ...
                                  'Icon','warning' ...
                                );
                        end
                    end

                    %if scatter plot
                    if strcmp(handles.plotType,'Scatter Plot')==1
            
                        %check ignore zeros checkbox
                        if handles.ignoreZerosCheckbox.Value == 1
                            idx1 = cellfun(@(c) isequal(c,0), plotData(:,1));
                            plotData(idx1,:) = [];
                            idx2 = cellfun(@(c) isequal(c,0), plotData(:,2));
                            plotData(idx2,:) = [];
                        end
            
                        %check log checkbox
                        if handles.logXCheckbox.Value == 1
                            plotData(:,1) = cellfun(@log, plotData(:,1),'UniformOutput',false);
                        end
                        if handles.logYCheckbox.Value == 1
                            plotData(:,2) = cellfun(@log, plotData(:,2),'UniformOutput',false);
                        end
           
            
                        %if categories selected to restrict data, add to legend
                        if exist('selCol','var')
                            selColSize = length(selCol);
                            selColPairs = cell(1,selColSize);
                            for i = 1:selColSize
                                tempReally  = sprintf('%s:%s',selCol{i}, selCat{i});
                                selColPairs{i} = tempReally;
                            end
                            catStr = strjoin(selColPairs,', ');
                            handles.legend{end+1} = [catStr,' (n = ',num2str(size(plotData,1)),')'];
                        elseif isempty(handles.narrowedList.String{1})
                            handles.legend{end+1} = ['All (n = ',num2str(size(plotData,1)),')'];
                        end
            
                        %plot data; removing old plotted lines/points from clicking
                        %"display additional information" so that they do not overlap
                        delete(handles.r2Text);
                        delete(handles.plotmdl);
                        delete(legend);
                            
                        if app.AverageRowsCheckBox.Value
                            % — average all points with the same ID before plotting —
                            % idCol was set earlier (e.g. idCol = 3 for [x y ID …])
                            ids  = cell2mat(plotData(:, idCol));    
                            xRaw = cell2mat(plotData(:,   1  ));    
                            yRaw = cell2mat(plotData(:,   2  ));    
                            uniq = unique(ids);
                            xData = arrayfun(@(u) mean( xRaw(ids==u) ), uniq);
                            yData = arrayfun(@(u) mean( yRaw(ids==u) ), uniq);
                        else
                            % — original behavior —
                            xData = cell2mat(plotData(:,1));
                            yData = cell2mat(plotData(:,2));
                        end


                        plot(xData,yData,[handles.markerColor,handles.markerType],'LineStyle','none','MarkerSize',10);
            
                        %set up axis labels and get units from units dictionary
                        Xax = colXLabel;
                        Yax = colYLabel;

                        Xax = strrep(Xax,'_','\_');
                        

                        Yax = strrep(Yax,'_','\_');
                        
                        xlabel(Xax,'FontSize',14,'Interpreter','tex','FontWeight','bold');
                        ylabel(Yax,'FontSize',14,'Interpreter','tex','FontWeight','bold');
            
                        %set up title/legend
                        legend(strrep(handles.legend,'_','\_'),'FontSize',10)
                        plotTitle = [Yax,' vs. ',Xax];

                 
                        title(linewrap(app,strrep(strrep(plotTitle,'\_','_'),'_','\_'),50),'FontSize',16,'Interpreter','tex','FontWeight','bold');
            
                        %get R^2 calculations
                        if isscalar(handles.mainAxes.Children)
                            handles.mdl = fitlm(cell2mat(plotData(:,1)),cell2mat(plotData(:,2)));
                        else %if there are multiple groups, join groups before calculating r2 value
                            handles.mdl = [];

                            % First compute total number of points
                            nGroups = numel(handles.savePlot);
                            lengths = zeros(nGroups,1);
                            for gps = 1:nGroups
                                lengths(gps) = size(handles.savePlot{gps},1);
                            end
                            totalPts = sum(lengths);
                            
                            % Pre-allocate
                            allX = zeros(totalPts,1);
                            allY = zeros(totalPts,1);
                            
                            % Fill in block by block
                            pos = 0;
                            for gps = 1:nGroups
                                data = handles.savePlot{gps};
                                n    = lengths(gps);
                                
                                xs = cell2mat(data(:,1));
                                ys = cell2mat(data(:,2));
                                
                                allX(pos + (1:n)) = xs;
                                allY(pos + (1:n)) = ys;
                                
                                pos = pos + n;
                            end
                            
                            % Fit the model once at the end
                            handles.mdl = fitlm(allX, allY);

                            statsPositionMenu_Callback(app,event)
                        end

                        % Add 5% padding on each side
                        xRange = max(xData) - min(xData);
                        yRange = max(yData) - min(yData);
                        
                        xMargin = 0.05 * xRange;
                        yMargin = 0.05 * yRange;
                        
                        %automatically "fits" the graph around the plot points with 5% padding 
                            set(handles.mainAxes, ...
                    'XLim', [min(xData) - xMargin, max(xData) + xMargin], ...
                    'YLim', [min(yData) - yMargin, max(yData) + yMargin]);
                            
                    %if box plot
                 elseif strcmp(handles.plotType,'Box Plot') == 1
            
                        %check ignore zeros checkbox
                        if handles.ignoreZerosCheckbox.Value == 1
                            idx1 = cellfun(@(c) isequal(c,0), plotData(:,1));
                            plotData(idx1,:) = [];
                        end
            
                        %check log checkbox
                        if handles.logYCheckbox.Value == 1
                            plotData(:,1) = cellfun(@log, plotData(:,1),'UniformOutput',false);
                        end
            
            
                        % — average by ID if requested —
                        rawBP = plotData;    % cell array of [value, ID, …]
                        if app.AverageRowsCheckBox.Value
                            ids      = cell2mat(rawBP(:,2));      % extract ID column
                            vals     = cell2mat(rawBP(:,1));      % extract value column
                            uniqIDs  = unique(ids);
                            plotVals = arrayfun(@(u) mean(vals(ids==u)), uniqIDs);
                        else
                            plotVals = cell2mat(rawBP(:,1));
                        end
            
                        %clear axes so boxes don't overlap
                        cla reset;
            
                        %plot data
                        if isempty(handles.narrowedList.String{1})
                            catStr = 'All Groups';
                        else
                            narrowedListSize = numel(handles.narrowedList.String);
                            catStrCells = cell(narrowedListSize,1);
                            for i = 1:narrowedListSize
                                catStrCells{i} = handles.narrowedList.String{i};
                            end
                            catStr = strjoin(catStrCells,', ');
                        end
                        fullGroupName = [colYLabel,' - ',catStr]; 
                        fullGroupName = strrep(fullGroupName,'_','\_');
                        handles.boxVals = vertcat(handles.boxVals,plotVals);
                        handles.boxCats = vertcat(handles.boxCats,repmat({fullGroupName},size(plotVals)));
                        boxplot(handles.boxVals,strcat(handles.boxCats)); 
                       
                        %Makes lines thicker, I think it looks ugly
                        %set(findobj(gca,'type','line'),'linew',3); 
                        
                        handles.mainAxes.FontSize = 12;
            
                        %set up titles
                        if isempty(handles.titleCats)
                            handles.titleCats = fullGroupName;
                        else
                            handles.titleCats = [handles.titleCats,' and ',fullGroupName];
                        end
                        plotTitle = handles.titleCats;                        
                        title(handles.mainAxes,linewrap(app,strrep(strrep(plotTitle,'\_','_'),'_','\_'),50),'FontSize',16,'Interpreter','tex','FontWeight','bold');


                        Yax=strrep(colY,'_','\_');
                        

                        ylabel(handles.mainAxes,Yax,'FontSize',14,'Interpreter','tex','FontWeight','bold');
                        xlabel(handles.mainAxes,'');
            
                        %center and format tick labels
                        fix_xticklabels(app,gca,0.1,{'FontSize',13});
                        handles.mainAxes.XTickLabel = strrep(handles.mainAxes.XTickLabel,'_','\_');
                        handles.mainAxes.TickLabelInterpreter = 'tex';
            
                        %calc p value if 2 groups
                        numCats = unique(handles.boxCats,'stable');
                        if numel(numCats) == 2
                            group1 = handles.boxVals(strcmp(handles.boxCats,fullGroupName));
                            group2 = handles.boxVals(~strcmp(handles.boxCats,fullGroupName));
            
                            %calculate p-val
                            [~,p] = ttest2(group1,group2);
                            if p < 0.001
                                handles.pVal = 'p < 0.001';
                            else
                                handles.pVal = ['p = ',num2str(p,3)];
                            end
                            boxCheckBox_Callback(app,event)
                        else
                            handles.pVal = [];
                            boxCheckBox_Callback(app,event)
                        end
            
                        handles.boxN = [];
                        for a = 1:numel(numCats)
                            getGroup = handles.boxVals(strcmp(handles.boxCats,numCats{a}));
                            handles.boxN{end+1} = num2str(numel(getGroup));
                        end
                    
                  elseif strcmp(handles.plotType,'Histogram') == 1

                        % — your existing data prep —
                        % cell array of [value, ID]
                        rawHist = plotData;    
                        if app.AverageRowsCheckBox.Value
                            ids  = cell2mat(rawHist(:,2));
                            vals = cell2mat(rawHist(:,1));
                            uniq = unique(ids);
                            vec  = arrayfun(@(u) mean(vals(ids==u)), uniq);
                        else
                            vec = cell2mat(rawHist(:,1));
                        end
                        if handles.ignoreZerosCheckbox.Value
                            vec(vec==0) = [];
                        end
                        nbins = handles.nBins;
                    
                        % — overlay without clearing —
                        hold(handles.mainAxes,'on');
                        h = histogram(handles.mainAxes, vec, nbins, ...
                                      'FaceColor', handles.markerColor, ...
                                      'FaceAlpha', 0.4);

                            if ~isfield(handles, 'histData')
                                handles.histData = {};
                                handles.histCols = {};
                            end
                            handles.histData{end+1} = vec;
                            handles.histCols{end+1} = colY;

                    
                        % — legend bookkeeping —
                        handles.histLegend{end+1} =  sprintf('%s (n = %d)', strrep(colY,'_','\_'),  numel(vec));
                        legend(handles.histLegend, 'Location','best');
                    
                        % — accumulate **all** edges so far —
                        newEdges = h.BinEdges(:);
                        handles.histEdges = unique([handles.histEdges; newEdges]);
                    
                        % — re‐apply ticks & labels for the union of every histogram’s edges —
                        tickLoc    = handles.histEdges;
                        tickLabels = arrayfun(@num2str, tickLoc, 'UniformOutput', false);
                        set(handles.mainAxes, ...
                            'XLim',      [min(tickLoc), max(tickLoc)], ...
                            'XTick',     tickLoc, ...
                            'XTickLabel',tickLabels);
                    
                        % — re‐draw your axis labels/title —
                        if isempty(handles.xLabelGraphText)
                            handles.xLabelGraphText = [strrep(colYLabel,'_','\_')];

                        else
                             handles.xLabelGraphText = [handles.xLabelGraphText , ' and ', strrep(colY,'_','\_')];
                        end
                     
                        if isempty(handles.narrowedList.String{1})
                            catStr = '';
                        else
                            catStr = strjoin(handles.narrowedList.String, ', ');
                        end

                        if strcmp(catStr,'')
                            tempdsdsd  =  handles.xLabelGraphText;
                        else
                           tempdsdsd  = [ handles.xLabelGraphText,' - ', catStr];
                        end
                        xlabel(handles.mainAxes, tempdsdsd, ...
                               'FontSize',14,'Interpreter','tex','FontWeight','bold');
                        ylabel(handles.mainAxes, 'Count', 'FontSize',14,'FontWeight','bold');
                        
                        if ~strcmp(catStr,'')
                            title(handles.mainAxes, ...
                                  sprintf('Histogram of %s - %s', ...
                                          handles.xLabelGraphText, catStr), ...
                                  'FontSize',16,'Interpreter','tex','FontWeight','bold');
                        else
                            title(handles.mainAxes, ...
                                  sprintf('Histogram of %s', handles.xLabelGraphText), ...
                                  'FontSize',16,'Interpreter','tex','FontWeight','bold');
                        end
                    
                        % — save state —
                        guidata(hObject, handles);
                    end
                end
            end
            app.editTitlesButton.Enable = 'on';
            
            guidata(hObject,handles);
        end

        % Value changed function: plotTypeDropdown
        function plotTypeDropdown_Callback(app, event)
            % --- Executes on selection change in plotTypeDropdown.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to plotTypeDropdown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns plotTypeDropdown contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from plotTypeDropdown
            
            %get plot type from dropdown
            plotStr = get(hObject,'String');
            plotVal = get(hObject,'Value');
            handles.plotType = plotStr{plotVal};
            
            %marker types
            mTypes = {'o';'+';'*';'.';'x';'square';'diamond';'pentagon';'hexagon'};
            mColors = {'Red';'Yellow';'Magenta';'Cyan';'Green';'Blue';'Black'};
            
            %don't need marker types or x axis for boxplot
            switch handles.plotType
                case 'Scatter Plot'
                    set(handles.markerTypeDropdown,'String',mTypes);
                    set(handles.markerColorDropdown,'String',mColors);
                    set(handles.markerColorText,'String','Marker Color');
                    set(handles.xAxisParamList,'String',handles.tableInfo);
                    set(handles.xAxisParamList,'Value',1);
                    set(handles.xAxisParamList2,'String',handles.defaultList2);
                    set(handles.xAxisParamList2,'Value',1);
                    handles.markerType = handles.markerTypeDropdown.String{handles.markerTypeDropdown.Value};
                    handles.markerColor = handles.markerColorDropdown.String{handles.markerColorDropdown.Value};
                    set(handles.boxCheckBox,'visible','off');
                    set(handles.scatterCheckBox,'visible','on');
                    set(handles.logXCheckbox,'Enable','on');
                    if ~isempty(handles.groupParams)
                        updateinclCategoriesList(app,handles, handles.yAxisParamList.String{handles.yAxisParamList.Value} );
                    end

                    set(app.ChangeBinAmount,'visible','off');
                    set(app.NumberOfBinsLabel,'visible','off');
                    set(app.histogramCheckBox,'visible','off');
                    
                case 'Box Plot'
                    set(handles.markerTypeDropdown,'Value',1);
                    set(handles.markerTypeDropdown,'String',{'N/A'});
                    set(handles.markerColorDropdown,'Value',1);
                    set(handles.markerColorDropdown,'String',{'Default'});
                    set(handles.markerColorText,'String','Box Color');
                    set(handles.xAxisParamList,'Value',1);
                    set(handles.xAxisParamList,'String',{'N/A'});
                    set(handles.xAxisParamList2,'Value',1);
                    set(handles.xAxisParamList2,'String',{''});
                    set(handles.inclCategoriesList,'Value',1);
                    handles.markerType = 'N/A';
                    handles.markerColor = 'N/A';
                    set(handles.boxCheckBox,'visible','on');
                    set(handles.scatterCheckBox,'visible','off');
                    set(handles.logXCheckbox,'Enable','off');
                    if ~isempty(handles.groupParams)
                        updateinclCategoriesList(app,handles, handles.yAxisParamList.String{handles.yAxisParamList.Value} );
                    end


                    set(app.ChangeBinAmount,'visible','off');
                    set(app.NumberOfBinsLabel,'visible','off');
                    set(app.histogramCheckBox,'visible','off');

                case 'Histogram'
                    % only need the “Y” dropdown to pick which variable
                    set(handles.markerTypeDropdown, 'String', {'N/A'}, 'Value', 1);
                    set(handles.markerColorDropdown, 'String', mColors, 'Value', 1);
                    set(handles.markerColorText,  'String', 'Bar Color');
                    
                    % disable the X-axis controls
                    set(handles.xAxisParamList,   'Value', 1, 'String', {''});
                    set(handles.xAxisParamList2, 'Value', 1);
                    set(handles.xAxisParamList2, 'String', {''});
                    
                    % hide box/scatter–specific checkboxes
                    set(handles.boxCheckBox,     'Visible', 'off');
                    set(handles.scatterCheckBox, 'Visible', 'off');
                    
                    % logging makes less sense on a histogram axis
                    set(handles.logXCheckbox, 'Enable', 'off', 'Value', 0);
                    set(handles.logYCheckbox, 'Enable', 'off', 'Value', 0);
                    
                    % refresh the “include categories” list based on Y-axis
                    if ~isempty(handles.groupParams)
                        updateinclCategoriesList(app, handles, handles.yAxisParamList.String{handles.yAxisParamList.Value});
                    end

                    set(app.ChangeBinAmount,'visible','on');
                    set(app.NumberOfBinsLabel,'visible','on');
                    set(app.histogramCheckBox,'visible','on');

                       handles.histLegend     = {};
                        handles.histXLim       = [];
                        handles.histXTick      = [];
                        handles.histXTickLabel = {};
                        handles.histEdges  = [];
                        handles.xLabelGraphText = [];

                    handles.markerColor = handles.markerColorDropdown.String{handles.markerColorDropdown.Value};

                    set(handles.mainAxes, 'NextPlot','add');    % same as hold on
                    guidata(hObject, handles);

            end
            handles.titleCats = [];
            handles.boxN = [];
            handles.savePlot = [];
            handles.pVal = [];
            handles.mdl = [];
            handles.plotmdl = [];
            set(handles.boxCheckBox,'Value',0);
            set(handles.scatterCheckBox,'Value',0);
            handles.r2Text = [];
            handles.pText = [];
            handles.numText = [];
            set(handles.mainAxes,'FontSize',12);
            set(handles.narrowedList,'String',{''});
            set(handles.narrowedList, 'min', 0, 'max', 2);
            set(handles.narrowedList,'Value',[]);
            set(handles.logXCheckbox,'Value',0);
            set(handles.logYCheckbox,'Value',0);
            set(handles.ignoreZerosCheckbox,'Value',0);
            
            guidata(hObject,handles)
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            % --- Executes on button press in saveButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            hold(handles.mainAxes,'off');
            
            %check if there is data plotted
            if isempty(handles.mainAxes.Children)
                errordlg('There is no data plotted.','Save Error');
            end
            
            %generate file name

            if (~iscell (handles.mainAxes.Title.String))
                getTitle = strjoin({handles.mainAxes.Title.String},{' '});
            else 
               getTitle = strjoin(handles.mainAxes.Title.String,{' '});
            end
            

            splitTitle = strsplit(getTitle,'for');
            splitTitle = strsplit(splitTitle{1},'using');
            fileTitle = strrep(strrep(strrep(strrep(strrep(splitTitle{1},'\_','_'),' ',''),'.',''),':','-'),'*','');
            fileTitle = regexprep(fileTitle,'\([^\(\)]*\)','');
            if contains(fileTitle,'>=')
                fileTitle = strrep(fileTitle,'>=','geq');
            end
            if contains(fileTitle,'<=')
                fileTitle = strrep(fileTitle,'<=','leq');
            end
            
            %check amp threshold & inversion type
            splitTitle2 = strsplit(getTitle,' ');
            findInv = regexpi(splitTitle2,'Inversion');
            if sum(~cellfun(@isempty,findInv)) ~= 0 %title includes 'Inversion'
                invType = splitTitle2{find(~cellfun(@isempty,findInv))-1};
                thresh = regexpi(getTitle,'Amplitude Threshold');
                if ~isempty(thresh)
                    if strcmp(getTitle(thresh-3:thresh-2),'No') == 1
                        threshVal = 'No';
                    else
                        threshVal = getTitle(thresh-4:thresh-3);
                    end
                end
                fileTitle = [fileTitle,'_',invType,threshVal,'Amp'];
            end
            
            %copy to temp fig and save
            app.figure1.Visible = 'off';
            [fileName,pathName]=uiputfile({'*.png';'*.mat';'*.fig'},'Save plot as',[handles.savePath,fileTitle,'.png']);
             app.figure1.Visible = 'on';
            if isequal(fileName,0) || isequal(pathName,0) || ~exist(pathName,'dir')
                errordlg('Please enter a valid file or path name.','Save Error');
            else
                handles.savePath = pathName; %save new path as default
            
                %save based on file extension - if fig or png, save figure; if mat,
                %save figure data (x and y values).
                getType = strsplit(fileName,'.');
                imType = getType{2};
                if strcmp(imType,'png') == 1 || strcmp(imType,'fig') == 1
                    tempFig=figure();
            
                    getLeg = legend(handles.mainAxes);
                    legStr = get(getLeg,'String');
                    legLoc = get(getLeg,'Position');
                    tempFigObj=copyobj(handles.mainAxes,tempFig);
                    set(tempFigObj,'Units','normalized','Position',[0.1 0.1 0.85 0.8]);
                    set(tempFig,'Units','normalized','Position',[0 0.1 0.3 0.8]);
            
                    %copy over legend
                    if ~isempty(legStr)
                        legend(tempFigObj,legStr,'Position',legLoc);
                    end
            
                    if strcmp(imType,'fig') == 1
                        saveas(tempFig,fullfile(pathName,fileName(1:end-4)),imType);
                    else
                        set(gcf,'color','w');
                        f = getframe(gcf);
                        [X,~] = frame2im(f);
                        imwrite(X,[fullfile(pathName,fileName(1:end-4)),'.png']);
                    end
                    close(gcf);
                end
            
                %format structs if user saves as mat
                if strcmp(imType,'mat') == 1 && strcmp(handles.plotType,'Scatter Plot') == 1
                    for a = 1:numel(handles.savePlot)
                        groupName = ['Group_',num2str(a)'];
                        groupData = handles.savePlot{a};
                        plotData.(groupName) = struct('xvalues',cell2mat(groupData(:,1)),'yvalues',cell2mat(groupData(:,2)),'ID',cell2mat(groupData(:,3)));
                        if size(handles.savePlot{a},2) == 4
                            plotData.(groupName).visit = cell2mat(groupData(:,4));
                        end
                    end
                    save([pathName,fileName],'plotData');
                elseif strcmp(imType,'mat') == 1 && strcmp(handles.plotType,'Box Plot') == 1
                    for a = 1:numel(handles.savePlot)
                        groupName = ['Group_',num2str(a)'];
                        groupData = handles.savePlot{a};
                        plotData.(groupName) = struct('yvalues',cell2mat(groupData(:,1)),'ID',cell2mat(groupData(:,2)));
                        if size(handles.savePlot{a},2) == 3
                            plotData.(groupName).visit = cell2mat(groupData(:,3));
                        end
                    end
                    save([pathName,fileName],'plotData');
                end
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: scatterCheckBox
        function scatterCheckBox_Callback(app, event)
            % --- Executes on button press in scatterCheckBox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clearPlotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %store title values
            axes(handles.mainAxes)
            axesTitle = handles.mainAxes.Title.String;
            axesY = handles.mainAxes.YLabel.String;
            axesX = handles.mainAxes.XLabel.String;
            
            %set up strings for volumes if MDEV2/Sanchez data is plotted
            volStr = [];

            
            %plot mdl and add textbox for R^2 value
            if ~isempty(handles.mdl)
                if get(handles.scatterCheckBox,'Value') == 1
            
                    %plot mdl overwrites marker legend, so need to copy over into a
                    %separate set of axes temporarily
                    groups = numel(handles.savePlot);
                    if groups > 1
                        l1 = legend;
                        temp = figure();
                        set(temp,'pos',[0 0 0 0]);
                        l1 = copyobj([l1 handles.mainAxes],temp); %#ok<NASGU>
                    end
                    axes(handles.mainAxes)
                    handles.plotmdl = plot(handles.mainAxes,handles.mdl);
                    l = legend;
            
                    %textbox positioning - default is top left
                    x = xlim(handles.mainAxes);
                    y = ylim(handles.mainAxes);
                    xpos = (x(2)-x(1))*0.03+x(1);
                    ypos = (y(2)-y(1))*0.97+y(1);
            
                    %set up title
                    tempMore = strrep(axesTitle,'\_','_');
                    if (~iscell(tempMore))
                        tempMore = {tempMore};
                    end
                      
                    title(linewrap(app,strjoin(tempMore,{' '})  ,50),'FontSize',16,'FontWeight','bold');
                    ylabel(strrep(axesY,'\_','_'),'FontSize',14,'FontWeight','bold');
                    xlabel(strrep(axesX,'\_','_'),'FontSize',14,'FontWeight','bold');
            
                    %r2 text box
                    r2str = ['R^{2} = ',num2str(handles.mdl.Rsquared.Ordinary,3)];
                    linFit = polyfit(handles.plotmdl(2).XData,handles.plotmdl(2).YData,1);
                    %mdlSlope = mean(diff(handles.plotmdl(2).YData)./diff(handles.plotmdl(2).XData));
                    mdlSlope = linFit(1);
                    mdlIntercept = linFit(2);
                    slopestr = [num2str(mdlSlope,3)];
                    interceptstr = ['Intercept/Slope = ',num2str(mdlIntercept,5)];
                    pval = ['p-Value = ',num2str(coefTest(handles.mdl),3)];
                    numSubs = ['n = ',num2str(numel(handles.plotmdl(1).XData))];
                    str = sprintf('%s\n%s  %s\n%s\n%s%s',r2str,interceptstr,slopestr,pval,numSubs,volStr);
                    handles.r2Text = text(xpos,ypos,str,'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
            
                    %copy marker legend back over onto original axes
                    if groups > 1
                        delete(l);
                        l1 = findobj(temp,'type','legend');
                        legend(l1.String);
                        close(temp);
                        l = legend;
                    end
            
                    %textbox positioning based on dropdown
                    pos = handles.statsPositionMenu.String{handles.statsPositionMenu.Value};
                    if strcmp(pos,'Top')
                        if l.Position(1)<0.5 && l.Position(2)>0.5 %shift to top right if legend is in top left
                            xpos = (x(2)-x(1))*0.97-handles.r2Text.Extent(3)+x(1);
                            delete(handles.r2Text);
                            handles.r2Text = text(xpos,ypos,str,'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                        end
                    else
                        %xpos = (x(2)-x(1))*0.97-handles.r2Text.Extent(3)+x(1);
                        %ypos = (y(2)-y(1))*0.03+handles.r2Text.Extent(4)+y(1);
                        if l.Position(1)>0.5 && l.Position(2)<0.5 %shift to bottom left if legend is in bottom right
                            xpos = (x(2)-x(1))*0.03+x(1);
                        end
                        delete(handles.r2Text);
                        handles.r2Text = text(xpos,ypos,str,'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                    end
                else
                    %delete everything if checkbox is not checked
                    delete(handles.plotmdl);
                    delete(handles.r2Text);
                    mdlLeg = legend;
                    if isvalid(mdlLeg)
                        if isequal(mdlLeg.String,{'Data','Fit','Confidence bounds'})
                            delete(mdlLeg);
                        end
                    end
                    legend(strrep(handles.legend,'_','\_'),'FontSize',10);
                end
            else
                %if there is no fitlm data, remove existing legends/textboxes and
                %create a textbox with just pixel values (no r^2 data)
                if ~isempty(handles.plotmdl)
                    delete(handles.plotmdl(:));
                    mdlLeg = legend;
                    if ~isempty(mdlLeg)
                        if isequal(mdlLeg.String,{'Data','Fit','Confidence bounds'})
                            delete(mdlLeg);
                        end
                    end
                    delete(handles.r2Text);
                end
            
                %textbox positioning
                x = xlim(handles.mainAxes);
                y = ylim(handles.mainAxes);
                xpos = (x(2)-x(1))*0.03+x(1);
                ypos = (y(2)-y(1))*0.97+y(1);
            
                %if checked, disp pixel values
                if get(handles.scatterCheckBox,'Value') == 1
                    if ~isempty(volStr)
                        handles.r2Text = text(xpos,ypos,volStr(2:end),'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                        pos = handles.statsPositionMenu.String{handles.statsPositionMenu.Value};
                        if strcmp(pos,'Bottom')
                            %xpos = (x(2)-x(1))*0.97-handles.r2Text.Extent(3)+x(1);
                            ypos = (y(2)-y(1))*0.03+handles.r2Text.Extent(4)+y(1);
                            delete(handles.r2Text);
                            handles.r2Text = text(xpos,ypos,volStr(2:end),'Parent',handles.mainAxes,'verticalalignment','top','horizontalalignment','left','edgecolor','k','backgroundcolor','w','FontSize',11);
                        end
                    end
                else
                    delete(handles.r2Text);
                end
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: selectDbMenu
        function selectDbMenu_Callback(app, event)
            % --- Executes on selection change in selectDbMenu.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selectDbMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            %Have to enter name first for logging reasons
            while ( strcmp(app.NameLabel.Text,"No Name Selected" ) == 1)
                EditNameButton_Callback(app,event);
            end


            %get selected database
            dbName = handles.sqlitePaths{handles.selectDbMenu.Value};
            dbName = fullfile(pwd, dbName);


            if strcmp(dbName,handles.dbDefaultMessage) == 1
                error('Please select a database.');
            end
            
            %get rid of "no database selected" from list after first selection

            firstTime = false;

            if strcmp(handles.selectDbMenu.String{1},handles.dbDefaultMessage) == 1
                handles.selectDbMenu.Value = handles.selectDbMenu.Value - 1;
                handles.sqlitePaths(1) = [];
                handles.selectDbMenu.String(1) = [];
                firstTime = true;
            end


            handles.currentSqliteOpened = dbName;

            % Get file name and extension separately
            [~, name, ext] = fileparts(handles.currentSqliteOpened);

            handles.currentSqliteOpenedName = name;

  
            % Get current UTC date and time
            nowUTC = datetime('now','TimeZone','UTC','Format','yyyyMMdd_HHmmss');


            % Create output filename
            outputName = sprintf('/%s_SavedAt_%s%s', name, string(nowUTC), ext);
            
            % Combine with path (if needed)

            
            absPath = fullfile(pwd, handles.config.sqliteDir);


            outputFile = fullfile(absPath, '/Save/', outputName);
            
            % Save the sqlite
            copyfile(handles.currentSqliteOpened, outputFile);
            handles.filesOpenedTable(end+1,:) = {handles.currentSqliteOpened, outputFile};


            t = datetime('now','TimeZone','UTC');

            if firstTime == 1
                handles.timeWhenFirstSqlOpened = t;
            end

            logMessage = sprintf('%s saved %s to %s at %02d/%02d/%04d at %02d:%02d and %02d seconds \n\n', app.NameLabel.Text, handles.currentSqliteOpened, outputFile,...
            month(t), day(t), year(t), hour(t), minute(t), floor(second(t)));


            fprintf(handles.logFile,logMessage);

            handles.previousPlotType = '';

            guidata(hObject, handles);


            %load database
            handles = loadDbParameters(app,handles.currentSqliteOpened,handles);
            
            %remove highlight from select database panel
            set(handles.selDbPanel,'HighlightColor','w','ForegroundColor','k','FontWeight','normal','BorderWidth',1);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: statsPositionMenu
        function statsPositionMenu_Callback(app, event)
            % --- Executes on button press in statsPositionMenu.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clearPlotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if get(handles.scatterCheckBox,'Value') == 1
                delete(handles.plotmdl);
                delete(handles.r2Text);
                mdlLeg = legend;
            
                %delete auto-generated legend from calling fitlm - if this can't be
                %found, need to go in and check the axes children for these names
                if isvalid(mdlLeg)
                    if isequal(mdlLeg.String,{'Data','Fit','Confidence bounds'})
                        delete(mdlLeg);
                    end
                end
                for i = length(handles.mainAxes.Children):-1:1
                    checkStr = {'Confidence bounds','Fit','Data',''};
                    for j = 1:3
                        if strcmp(handles.mainAxes.Children(i).DisplayName,checkStr{j})
                            delete(handles.mainAxes.Children(i));
                        end
                    end
                end
            
                %remove original text box
                getBox = findobj(gca,'Type','Text');
                if ~isempty(getBox)
                    for i = 1:numel(getBox)
                        if strcmp('R^{2}',getBox(i).String{1}(1:5)) == 1
                            delete(getBox(i));
                        end
                    end
                end
            
                %call to scatterCheckBox will look at selected dropdown option and
                %reposition
                scatterCheckBox_Callback(app,event)
            
            elseif get(handles.boxCheckBox,'Value') == 1
            
                %remove original text box
                delete(handles.pText);
                delete(handles.numText);
                getBox = findobj(gca,'Type','Text');
                if ~isempty(getBox)
                    for i = 1:numel(getBox)
                        if strcmp('n (Group',getBox(i).String{1}(1:8)) == 1 || strcmp('p = ',getBox(i).String{1}(1:4)) == 1
                            delete(getBox(i));
                        end
                    end
                end
            
                %call to boxCheckBox will look at selected dropdown option and
                %reposition
                boxCheckBox_Callback(app,event);
            end
        end

        % Value changed function: xAxisParamList2
        function xAxisParamList2_Callback(app, event)
            % --- Executes on selection change in xAxisParamList2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to xAxisParamList2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns xAxisParamList2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from
            %        xAxisParamList2


            guidata(hObject,handles)
        end

        % Value changed function: xAxisParamList
        function xAxisParamList_Callback(app, event)
            % --- Executes on selection change in xAxisParamList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to xAxisParamList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns xAxisParamList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from xAxisParamList
            
            %set params so that they only show columns from the table selected
            colSplit = regexp(handles.axisParams,': ','split');
            regroupCol = cat(1,colSplit{:});
            xTable = handles.xAxisParamList.String{handles.xAxisParamList.Value};
            groupList = regroupCol(:,2);
            groupList = groupList(strcmp(regroupCol(:,1),xTable));
            
            %remove frequencies from column names (these will be populated in the
            %x/y axis freqList
            rmFreq = groupList;

            rmFreq = unique(rmFreq);

            
            txt = app.XAxisParametersFilterEditField.Value;
            if ~isempty(txt)
                idx = contains(rmFreq, txt, 'IgnoreCase', true);
                rmFreq = rmFreq(idx);
            end
            

            set(handles.xAxisParamList2,'visible','on');

            if isempty(rmFreq)
                set(handles.xAxisParamList2, 'String', {''}, 'Value',1, 'ListBoxTop',1);
            else
                set(handles.xAxisParamList2, 'String', rmFreq, 'Value',1, 'ListBoxTop',numel(rmFreq));
            end
            
            
            guidata(hObject,handles)
        end

        % Button pushed function: y2GroupButton
        function y2GroupButton_Callback(app, event)
            % --- Executes on button press in y2GroupButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to y2GroupButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get y parameter
            ycomb = {handles.yAxisParamList2.String{handles.yAxisParamList2.Value}};
            tableY = handles.yAxisParamList.String{handles.yAxisParamList.Value};
            colY = strjoin(ycomb(~cellfun(@isempty,ycomb)),'_');
            
            %set up strings
            listSize = size(handles.groupParams,1);
            handles.groupParams{listSize+1,1} = [colY, ': NUM VALUE'] ; %greater than
            handles.groupParams{listSize+1,2} = tableY;
            
            %get rid of repeating entries
            [~,idx] = unique(handles.groupParams(:,1),'first');
            handles.groupParams = handles.groupParams(sort(idx),:);
            
            % update list

               updateinclCategoriesList(app,handles,tableY);


             sz = size(handles.inclCategoriesList);


            set(handles.inclCategoriesList,'Value',sz);
            
            guidata(hObject,handles);
        end

        % Value changed function: yAxisParamList2
        function yAxisParamList2_Callback(app, event)
            % --- Executes on selection change in xAxisParamList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to yAxisParamList2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns yAxisParamList2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from
            %        yAxisParamList2


            guidata(hObject,handles)
        end

        % Value changed function: yAxisParamList
        function yAxisParamList_Callback(app, event)

            app.YAxisParametersFilterEditField.Enable = 'off';

            % --- Executes on selection change in yAxisParamList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to yAxisParamList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns yAxisParamList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from yAxisParamList
            
            %set params so that they only show columns from the table selected
            colSplit = regexp(handles.axisParams,': ','split');
            regroupCol = cat(1,colSplit{:});
            yTable = handles.yAxisParamList.String{handles.yAxisParamList.Value};
            groupList = regroupCol(:,2);
            groupList = groupList(strcmp(regroupCol(:,1),yTable));
            
            set(handles.yAxisParamList2,'Value',1);
            handles.yAxisParamList2.ListboxTop = length(groupList);
            handles.yAxisParamList2.String = groupList;
            set(handles.yAxisParamList2,'visible','on');
            
            %remove frequencies from column names (these will be populated in the
            %x/y axis freqList
            rmFreq = groupList;
            rmFreq = unique(rmFreq);


            if (~isempty(app.YAxisParametersFilterEditField.Value))
                idx    = contains(rmFreq, app.YAxisParametersFilterEditField.Value, 'IgnoreCase', true);
                rmFreq =  rmFreq(idx);
            end

            if isempty(rmFreq)
                % no hits: show a blank entry (or disable the listbox)
                set(handles.yAxisParamList2, ...
                    'String',    {''}, ...
                    'Value',     1,  ...
                    'ListBoxTop',1);
            else
                % normal case: populate with the matches
                set(handles.yAxisParamList2, ...
                    'String',    rmFreq, ...
                    'Value',     1,     ...
                    'ListBoxTop', numel(rmFreq));
            end


            updateinclCategoriesList(app,handles,yTable);
            app.YAxisParametersFilterEditField.Enable = 'on';

            guidata(hObject,handles)
        end

        % Button pushed function: CSVImportButton
        function CSVImportButton_Callback(app, event)
                    [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

                    % 1) pick CSV, read into “data” & varNames
                    app.figure1.Visible = 'off';  
                    [csvFile, csvPath] = uigetfile('*.csv','Select CSV file');
                    app.figure1.Visible = 'on';  

                    if isequal(csvFile,0), return; end
                    csvFullPath   = fullfile(csvPath,csvFile);
                    data          = readtable(csvFullPath);
                    varNames      = data.Properties.VariableNames;
                    conn = sqlite(handles.currentSqliteOpened,'connect');
                
                    % 2) pick table from your SQLite DB
                     % **NEW**: Ask user whether to import into existing or create new
                        choice = questdlg( ...
                            'Import into an existing table, or create a new one?', ...
                            'Import Mode', ...
                            'Existing','New','Existing');
                        if isempty(choice)
                            close(conn); return;
                        end

                        newTableFlag = false;
                    
                        if strcmp(choice,'New')
                                % **NEW**: suggest default table name = CSV filename without “.csv”
                                [ ~,defaultName , ~] = fileparts(csvFile);
                                answer = inputdlg( ...
                                    'Enter new table name:', ...
                                    'New Table', ...
                                    1, {defaultName});
                                if isempty(answer)
                                    close(conn); return;
                                end
                                tableName = matlab.lang.makeValidName(answer{1});  % ensure valid identifier
                        
                                % **NEW**: create it with only ID column
                                % ——— detect CSV’s ID column name & data ———
                                isIDcsv  = strcmpi(varNames,'ID') | strcmpi(varNames,'SubjectID');
                                csvIDcol = varNames{find(isIDcsv,1)};
                                rawIDs   = data.(csvIDcol);
                            
                                % ——— pick INTEGER vs TEXT based on CSV contents ———
                                if isnumeric(rawIDs)
                                    idColType = 'INTEGER';
                                elseif iscell(rawIDs)
                                    if all(cellfun(@(x) ~isnan(str2double(x)), rawIDs))
                                        idColType = 'INTEGER';
                                    else
                                        idColType = 'TEXT';
                                    end
                                elseif isstring(rawIDs)
                                    if all(~isnan(str2double(rawIDs)))
                                        idColType = 'INTEGER';
                                    else
                                        idColType = 'TEXT';
                                    end
                                else
                                    idColType = 'TEXT';
                                end
                            
                                newTableFlag = true;

                    
                        else
                            % **EXISTING**: your original “pick a table from the DB” code:
                            tbls = fetch(conn,"SELECT name FROM sqlite_master WHERE type='table'");
                            tbls = unique( table2array(tbls(:,1)) );
                            prompt = { sprintf('Select table from: %s', handles.currentSqliteOpenedName) };
                            [sel, ok] = listdlg( ...
                                'ListString', tbls, ...
                                'SelectionMode','single', ...
                                'PromptString', prompt, ...
                                'ListSize',[400,300]);
                            if ~ok
                                close(conn); return;
                            end
                            tableName = tbls{sel};
                        end


                    %At this point, clear the graph
                    clearPlotButton_Callback(app,event);
                
                    % 3) if it’s a brand-new table, there are no existing columns yet:
                    if newTableFlag
                        existingCols = {};
                    else
                        info         = fetch(conn, sprintf("SELECT name FROM pragma_table_info('%s')", tableName));
                        existingCols = table2array(info(:,1));
                    end

                
                    % 4) find ID column names in CSV & table
                    isIDcsv      = strcmpi(varNames,'ID') | strcmpi(varNames,'SubjectID');
                    csvIDcol     = varNames{find(isIDcsv,1)};
                    
                    if ~newTableFlag
                        isIDtbl  = strcmpi(existingCols,'ID') | strcmpi(existingCols,'SubjectID');
                        tblIDcol = existingCols{find(isIDtbl,1)};
                    end
                
                    % 5) show the import panel & clear old controls
                    app.ColumnSelectorPanel.Visible = 'on';
                    delete(app.ColumnScrollPanel.Children);
                
                    % 6) put a label at the top naming the table
                    app.tblLabel.Text       = ['Import into table:  ' tableName];
                    app.tblLabel.FontWeight = 'bold';

                    app.tblLabel.Position   = [10, app.ColumnSelectorPanel.Position(4)-30, 300, 22];
                
                    
                    % 7) Build exactly one Existing/New section with visible checkbox text
                    panelH = app.ColumnScrollPanel.Position(4);
                    y      = panelH - 10;   % start 10 pixels below top
                    
                    % 7a) Filter out the ID column
                    cols = varNames(~strcmpi(varNames, csvIDcol));
                    
                    % 7b) Partition into existing vs. new (case‐insensitive)
                    existingColsLower = lower(existingCols);

                    maskExisting = ismember(lower(cols),existingColsLower );
                    existingListCols = cols(maskExisting);
                    newList      = cols(~maskExisting);
                    
                    j = 0;  % index into app.ColumnItems
                    
                    % 7c) Header: Existing Columns
                    hdr1 = uilabel(app.ColumnScrollPanel);
                    hdr1.Text       = 'Existing Columns';
                    hdr1.FontWeight = 'bold';
                    hdr1.Position   = [10, y, 200, 22];
                    y = y - 25;
                    
                    % 7d) One row per existing column
                    for i = 1:numel(existingListCols) %#ok<FXUP>
                        vn = existingListCols{i};
                        j = j + 1;
                    
                        cb = uicheckbox(app.ColumnScrollPanel);
                        cb.Text     = vn;
                        cb.Value    = true;
                        cb.Position = [10, y, 200, 20];       % wide enough for text
                    
                        ef = uieditfield(app.ColumnScrollPanel,'text');
                        ef.Value    = vn;
                        ef.Position = [220, y, 120, 22];
                    
                        % store
                        app.ColumnItems(j).origName = vn;
                        app.ColumnItems(j).isNew    = false;
                        app.ColumnItems(j).cb        = cb;
                        app.ColumnItems(j).ef        = ef;
                    
                        y = y - 30;
                    end
                    
                    % 7e) Header: New Columns
                    hdr2 = uilabel(app.ColumnScrollPanel);
                    hdr2.Text       = 'New Columns';
                    hdr2.FontWeight = 'bold';
                    hdr2.Position   = [10, y, 200, 22];
                    y = y - 25;
                    
                    % 7f) One row per new column
                    for i = 1:numel(newList) %#ok<FXUP>
                        vn = newList{i};
                        j = j + 1;
                    
                        cb = uicheckbox(app.ColumnScrollPanel);
                        cb.Text     = vn;
                        cb.Value    = true;
                        cb.Position = [10, y, 200, 20];
                    
                        ef = uieditfield(app.ColumnScrollPanel,'text');
                        ef.Value    = vn;
                        ef.Position = [220, y, 120, 22];
                    
                        % store
                        app.ColumnItems(j).origName = vn;
                        app.ColumnItems(j).isNew    = true;
                        app.ColumnItems(j).cb        = cb;
                        app.ColumnItems(j).ef        = ef;
                    
                        y = y - 30;
                    end

                
                    % 8) hook up the Import button
                    app.DoImportButton.ButtonPushedFcn = @(~,~) doImport();


          %DisablesOtherButton
                        app.yAxisParamList.Enable = 'on';
            app.xAxisParamList.Enable = 'off';
            app.xAxisParamList2.Enable = 'off';
            app.yAxisParamList2.Enable = 'off';
            app.logYCheckbox.Enable = 'off';
            app.y2GroupButton.Enable = 'off';
            app.markerColorDropdown.Enable = 'off';
            app.markerTypeDropdown.Enable = 'off';
            app.inclCategoriesList.Enable = 'off';
            app.narrowedList.Enable = 'off';
            app.plotButton.Enable = 'off';
            app.clearPlotButton.Enable = 'off';
            app.saveButton.Enable = 'off';
            app.clearNarrowButton.Enable  = 'off';
            app.statsPositionMenu.Enable = 'off';
            app.ignoreZerosCheckbox.Enable = 'off';
            app.boxCheckBox.Enable = 'off';
            app.scatterCheckBox.Enable = 'off';
            app.plotTypeDropdown.Enable = 'off';
            app.CSVImportButton.Enable = 'off';
            app.ExportDatatoCSV.Enable = 'off';
            app.RevertChanges.Enable = 'off';

                
                    function doImport()

                        function colInfo = getColumnTypesByParsing(conn, tableName)
                            sql = sprintf("SELECT sql FROM sqlite_master WHERE type='table' AND name='%s';", tableName);
                            raw = fetch(conn, sql);
                        
                            if isempty(raw) || isempty(raw.sql{1})
                                error("Could not retrieve schema for table '%s'.", tableName);
                            end
                        
                            createStmt = raw.sql{1};
                        
                            % Extract the content between the parentheses
                            tokens = regexp(createStmt, '\((.*)\)', 'tokens', 'once');
                            if isempty(tokens)
                                error('Failed to parse CREATE TABLE statement.');
                            end


                            colDefs = split(tokens{1}, ',');
                            colDefsSize = numel(colDefs);
                            colInfo = cell(colDefsSize,2);
                        
                            for i = 1:colDefsSize %#ok<FXUP>
                                colLine = strtrim(colDefs{i});
                                % Match: column_name column_type
                                match = regexp(colLine, '^"?([\w]+)"?\s+([\w]+)', 'tokens', 'once');
                                if ~isempty(match)
                                    colInfo{i,1} = match{1};  % column name
                                    colInfo{i, 2} = match{2};   % column type
                                end
                            end
                        end


                        function val = getDefaultValue(type)                         
                            if contains(type, 'int')
                                val = '0';
                            elseif contains(type, 'real') || contains(type, 'float') || contains(type, 'double')
                                val = '0.0';
                            elseif contains(type, 'char') || contains(type, 'text')
                                val = '''''';
                            else
                                val = 'NULL';
                            end
                        end

                            conn.AutoCommit = 'off';

                            allItems = app.ColumnItems;
                            allItemSize = numel(allItems);

                            existingList = cell(allItemSize,1);
                            newList = cell(allItemSize,1);


                            items = struct('origName', {}, 'isNew', {}, 'cb', {}, 'ef', {});
                            override = app.OverrideData.Value;

                            exec(conn, 'SAVEPOINT import_sp');
                            
                            % Collect selected columns and classify
                            for k = 1:allItemSize
                                if allItems(k).cb.Value
                                    newName = allItems(k).ef.Value;
                                    items(end+1) = allItems(k);  %#ok<AGROW>
                            
                                    if ismember(lower(newName), existingColsLower)
                                        items(end).isNew = false;
                                        existingList{k} = newName;
                                    else
                                        items(end).isNew = true;
                                        newList{k} = newName;
                                    end
                                end
                            end
                            
                            existingList( cellfun('isempty', existingList) ) = [];
                            newList( cellfun('isempty', newList) ) = [];


                            % Format list strings with real newlines
                            existingStr = strjoin(existingList, newline);
                            newStr = strjoin(newList, newline);
                            
                            % Build the message using `sprintf`
                            Warning_message = sprintf([ ...
                                'Are you sure you want to continue?\n' ...
                                '\nThe following columns will be overwritten:\n%s' ...
                                '\n\nThe following columns will be added:\n%s'], ...
                                existingStr, newStr);
                            
                            % Show confirmation dialog
                            choice = questdlg(Warning_message, ...
                                'Confirm Import', ...
                                'Yes', 'No', 'No');
                            
                            if ~strcmp(choice, 'Yes')
                                return; % user cancelled
                            end


                                    % ——— NEW: If the table doesn't exist, create & import row-by-row ———
                                    tblExists = fetch(conn, sprintf( ...
                                        "SELECT name FROM sqlite_master WHERE type='table' AND name='%s';", ...
                                        tableName));
                                    if isempty(tblExists)
                                        % 0) re-discover the CSV’s ID column name and type:
                                        isIDcsv  = strcmpi(data.Properties.VariableNames,'ID') | ...
                                                   strcmpi(data.Properties.VariableNames,'SubjectID');
                                        csvIDcol = data.Properties.VariableNames{find(isIDcsv,1)};
                                
                                        % ─── force any SubjectID → ID ──────────────────────────────────────
                                        if strcmpi(csvIDcol,'SubjectID')
                                            tblIDcol = 'ID';
                                        else
                                            tblIDcol = csvIDcol;
                                        end
                                
                                        rawIDs = data.(csvIDcol);
                                        if isnumeric(rawIDs)
                                            idColType = 'INTEGER';
                                        elseif iscell(rawIDs) && all(cellfun(@(x) ~isnan(str2double(x)), rawIDs))
                                            idColType = 'INTEGER';
                                        else
                                            idColType = 'TEXT';
                                        end
                                
                                        % 1) Build CREATE TABLE: first the ID column, then all the user-selected/renamed fields
                                        origCols = arrayfun(@(x) x.origName, items,          'UniformOutput',false);
                                        newCols  = arrayfun(@(x) x.ef.Value,   items,         'UniformOutput',false);
                                        origColsFull = [{csvIDcol}, origCols];
                                        newColsFull  = [{tblIDcol},  newCols ];
                                
                                        colDefs = cell(1,numel(origColsFull));
                                        colDefs{1} = sprintf('"%s" %s', tblIDcol, idColType);
                                        for i = 2:numel(origColsFull) %#ok<FXUP>
                                            o = origColsFull{i};
                                            n = newColsFull{i};
                                            colDefs{i} = sprintf('"%s" %s', n, app.guessType(data.(o)));
                                        end
                                
                                        createSQL = sprintf( ...
                                            'CREATE TABLE "%s" (%s);', ...
                                            tableName, strjoin(colDefs,', '));
                                        exec(conn, createSQL);
                                
                                        % 2) Insert every CSV row, verbatim (duplicates included)
                                        for r = 1:height(data)
                                            literals = cellfun(@(o) app.toLiteral(data.(o)(r)), origColsFull, ...
                                                               'UniformOutput',false);
                                            insertSQL = sprintf( ...
                                              'INSERT INTO "%s" (%s) VALUES (%s);', ...
                                              tableName, ...
                                              strjoin(strcat('"',newColsFull,'"'),', '), ...
                                              strjoin(literals,', '));
                                            exec(conn, insertSQL);
                                        end
                                
                                        % 3) Commit & early return
                                        exec(conn,'RELEASE import_sp');
                                        close(conn);
                                        uialert(app.figure1,'Import complete!','Success');
                                
                                        app.ColumnSelectorPanel.Visible = 'off';
                                        handles = loadDbParameters(app, handles.currentSqliteOpened, handles);
                                        guidata(hObject, handles);
                                        return;
                                    end


                            % ============================
                            % NEW BLOCK: INSERT MISSING IDs
                            % ============================
                    
                                % ─── 1) pull CSV & existing IDs, coercing to a uniform type ───
                                colInfo = getColumnTypesByParsing(conn, tableName);
                                % look up the declared type for our ID column
                                idType  = lower( colInfo(strcmp(colInfo(:,1), tblIDcol), 2) );
                            
                                if contains(idType, 'int')
                                    % numeric IDs
                                    rawCSV     = data.(csvIDcol);
                                    % table might give cellstr—force to numeric
                                    if iscell(rawCSV)
                                        csvIDs = cellfun(@str2double, rawCSV);
                                    else
                                        csvIDs = double(rawCSV);
                                    end
                            
                                    tbl       = fetch(conn, sprintf("SELECT %s FROM %s", tblIDcol, tableName));
                                    rawExist  = tbl{:,1};
                                    if iscell(rawExist)
                                        existingIDs = cellfun(@str2double, rawExist);
                                    else
                                        existingIDs = double(rawExist);
                                    end
                                else
                                    % text IDs
                                    % force both sides to string arrays
                                    csvIDs      = string( data.(csvIDcol) );
                                    tbl         = fetch(conn, sprintf("SELECT %s FROM %s", tblIDcol, tableName));
                                    existingIDs = string( tbl{:,1} );
                                end
                            
                                % ─── 2) find the ones in CSV not already in the table ───
                                    newMask    = ~ismember(csvIDs, existingIDs);
                                    missingIDs = unique(csvIDs(newMask));
                                
                                    % drop any NaNs (only applies when ID column is numeric)
                                    if isnumeric(missingIDs)
                                        missingIDs = missingIDs(~isnan(missingIDs));
                                    end


                            if ~isempty(missingIDs)
                                % 4) grab the full column list (and default‐value behavior) from the schema
                                % get just the names (never NULL)
                                nameTbl = fetch(conn, sprintf( ...
                                    "SELECT name FROM pragma_table_info('%s');", ...
                                    tableName) );
                                colNames = table2array(nameTbl(:,1));   % cell array of strings
                                % find which position is your ID column
                                idColIndex =  strcmp(colNames,tblIDcol) == 1  ;

                                % 5) for each missing ID, INSERT using DEFAULT for every non-ID column
                                for m = 1:numel(missingIDs)
                                    idVal = missingIDs(m);
                                    % format the ID literal
                                    % format the ID literal (trim whitespace, escape quotes)
                                    if isnumeric(idVal)
                                        idLit = num2str(idVal);
                                    else
                                        % pull raw text, trim leading/trailing spaces
                                        rawID = char(idVal);
                                        rawID = strtrim(rawID);
                                        % escape any single-quotes inside
                                        escapedID = strrep(rawID, '''', '''''');
                                        % wrap in one pair of single-quotes
                                        idLit = ['''' escapedID ''''];
                                    end
                    
                                    % build the VALUES list
                                       colInfo = getColumnTypesByParsing(conn, tableName);

                                    vals = repmat({'DEFAULT'}, 1, numel(colNames));
                                   for k = 1:numel(colNames)
                                       typeForCol = lower( colInfo(k,2));
                                        vals(k) =  {getDefaultValue(typeForCol)};
                                   end

                                    vals{idColIndex} = idLit;
                    
                                    % turn into comma-separated strings
                                    % turn into comma-separated strings
                                    colList  = strjoin(colNames, ', ');
                                    % vals is already a 1×N cell array of character vectors, so just join:
                                    valList  = strjoin(vals, ', ');
                    
                                    % execute the INSERT
                                    sqlInsert = sprintf(...
                                      "INSERT INTO %s (%s) VALUES (%s);", ...
                                       tableName, colList, valList);
                                    exec(conn, sqlInsert);
                                end
                            end
                    
                            % ============================
                            % now continue with your existing per-column ALTER TABLE / UPDATE loop
                            % ============================


                            for k = 1:numel(items)
                        
                                oldName = items(k).origName;
                                newName = items(k).ef.Value;

                        
                                      % 1) If new, pick type based on whether all values are purely numeric
                                    if items(k).isNew
                                        colData = data.(oldName);
                            
                                        % determine “all digits?” condition
                                        if isnumeric(colData)
                                            allDigits = true;
                                        elseif iscell(colData)  % e.g. cellstr
                                            allDigits = all(cellfun(@(x) ~isnan(str2double(x)), colData));
                                        elseif isstring(colData)
                                            nums = str2double(colData);
                                            allDigits = all(~isnan(nums));
                                        else
                                            allDigits = false;
                                        end
                            
                                        % choose SQLite type
                                        colType = pickAndChoose(app,allDigits, 'REAL', 'TEXT');
                            
                                        % add column with that type
                                        % quote both table and column names
                                        sqlAdd = sprintf( ...
                                          'ALTER TABLE "%s" ADD COLUMN "%s" %s', ...
                                          tableName, newName, colType);
                                        exec(conn, sqlAdd);
                                    end

                        
                                % 2) Update row by row, building a literal SQL string
                                vals = data.(oldName);
                                ids  = data.(csvIDcol);
                                for r = 1:numel(ids)
                                        v = vals(r);
                                        if iscell(v)
                                            v = v{1};
                                        end
                                        id = ids(r);


                                    if override || ismember(id, missingIDs)
                                            
    
                                            % --- format the new-value literal ---
                                            % --- format the new-value literal, treating NaN as NULL ---
                                            if isnumeric(v)
                                                if isnan(v)
                                                    valLiteral = 'NULL';
                                                else
                                                    valLiteral = num2str(v);
                                                end
                                            elseif isstring(v) || ischar(v)
                                                raw = char(v);
                                                valLiteral = ['''' strrep(raw, '''', '''''') ''''];
                                            else
                                                raw = string(v);
                                                raw = char(raw);
                                                valLiteral = ['''' strrep(raw, '''', '''''') ''''];
                                            end
                                
                                        % --- format the ID literal as a single char row-vector ---
                                            if isnumeric(id)
                                                idLiteral = num2str(id);
                                            else
                                                % pull raw text, trim spaces
                                                if iscell(id)
                                                    rawID = id{1};
                                                elseif isstring(id)
                                                    rawID = char(id);
                                                else
                                                    rawID = id;
                                                end
                                                rawID = strtrim(char(rawID));
                                                % escape single-quotes
                                                escapedID = strrep(rawID, '''', '''''');
                                                % wrap in quotes
                                                idLiteral = ['''' escapedID ''''];
                                            end
                                        % --- build & execute once ---
                                        sqlUpdate = sprintf(...
                                          'UPDATE "%s" SET "%s" = %s WHERE "%s" = %s;', ...
                                          tableName, newName, valLiteral, tblIDcol, idLiteral);
                                        exec(conn, sqlUpdate);

                                    end
                                end
                            end
                            exec(conn, 'RELEASE import_sp');
                            close(conn);


                            % Format list strings with real newlines
                            %write to log
                            existingStr = strjoin(existingList, ', ');
                            newStr = strjoin(newList, ', ');

                            logMessage = [app.NameLabel.Text,' modified ', existingStr, ' and added ', newStr, ' to table ', tableName , ' from database '  handles.currentSqliteOpened];
                            fprintf(handles.logFile, logMessage);

                            uialert(app.figure1, 'Import complete!','Success');
                            app.ColumnSelectorPanel.Visible = 'off';
                             %load database
                            handles = loadDbParameters(app, handles.currentSqliteOpened,handles);
                             %remove highlight from select database panel
                            % set(handles.selDbPanel,'HighlightColor','w','ForegroundColor','k','FontWeight','normal','BorderWidth',1);
                            % Update handles structure
                            guidata(hObject, handles);
                        end
        end

        % Button pushed function: ExitImportCSV
        function ExitImportCSV_Callback(app, event)
             app.ColumnSelectorPanel.Visible = 'off';


            app.xAxisParamList.Enable = 'on';
            app.yAxisParamList.Enable = 'on';
            app.xAxisParamList2.Enable = 'on';
            app.yAxisParamList2.Enable = 'on';
            app.logYCheckbox.Enable = 'on';
            app.y2GroupButton.Enable = 'on';
            app.markerColorDropdown.Enable = 'on';
            app.markerTypeDropdown.Enable = 'on';
            app.inclCategoriesList.Enable = 'on';
            app.narrowedList.Enable = 'on';
            app.plotButton.Enable = 'on';
            app.clearPlotButton.Enable = 'on';
            app.saveButton.Enable = 'on';
            app.clearNarrowButton.Enable  = 'on';
            app.statsPositionMenu.Enable = 'on';
            app.ignoreZerosCheckbox.Enable = 'on';
            app.boxCheckBox.Enable = 'on';
            app.scatterCheckBox.Enable = 'on';
            app.plotTypeDropdown.Enable = 'on';
            app.CSVImportButton.Enable = 'on';
            app.ExportDatatoCSV.Enable = 'on';
            app.RevertChanges.Enable = 'on';

        end

        % Button pushed function: EditNameButton
        function EditNameButton_Callback(app, event)
                answer = inputdlg('Enter your name:', 'Edit Name', 1, {''});
                if ~isempty(answer)
                    newName = answer{1};
                    [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
                    if strcmp(app.NameLabel.Text,handles.NameLabel) == 1
                        app.EditNameButton.Text = 'Change Name';
                    end
                    app.NameLabel.Text = newName;
                    guidata(hObject, handles);
                end
        end

        % Button pushed function: AddSqliteFiles
        function AddSqliteFiles_Callback(app, event)

               [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

                            % 1) Let the user pick a .sqlite file
                [f, p] = uigetfile('*.sqlite', 'Select additional SQLite file');
                if isequal(f,0)
                    return   % user clicked Cancel
                end
                newFullPath = fullfile(p, f);
            
                % 2) Load the existing JSON config
                cfgPath = 'config.json';
                raw      = fileread(cfgPath);
                config   = jsondecode(raw);
            
                % 3) Ensure the field exists
                if ~isfield(config, 'additionalSqliteFiles') || isempty(config.additionalSqliteFiles)
                    config.additionalSqliteFiles = { };
                end
            
                % 4) Append if not already in the list
                if ~any(strcmp(config.additionalSqliteFiles, newFullPath))
                    config.additionalSqliteFiles{end+1} = newFullPath;                    
                end
            
                % 5) Write back to disk
                fid = fopen(cfgPath, 'w');
                fprintf(fid, '%s', jsonencode(config));
                fclose(fid);


                % 2d) Sort by the display name
                allNames = handles.selectDbMenu.String;


                currentDbMenuSelected = handles.selectDbMenu.String(handles.selectDbMenu.Value);
                allNames(end+1) = {f};
                [allNames, sortIdx] = sort(allNames);
                handles.sqlitePaths(end+1) = {newFullPath};


                %end
                handles.sqlitePaths = handles.sqlitePaths(sortIdx);


                    displayList = allNames;


                handles.selectDbMenu.String = displayList;

                 newDbValue = find(strcmp(handles.selectDbMenu.String,  currentDbMenuSelected)== 1); 

                 handles.selectDbMenu.Value = newDbValue;


                % 6) Update your handles and UI
                handles.config = config;              % save the updated config
                guidata(app.figure1, handles);

        end

        % Button pushed function: RevertChanges
        function RevertChanges_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % copies from save data
            length = numel(handles.filesOpenedTable(:,1));
            for i  = 1:length
                opened = handles.filesOpenedTable{i,2}{1};
                old = handles.filesOpenedTable{i,1}{1};
                copyfile(opened,old);
            end
            uialert(app.figure1,'All Changes Reverted!','Success');
            t = handles.timeWhenFirstSqlOpened;
            logMessage = sprintf('%s reverted all changes in the .SQL since %02d/%02d/%04d at %02d:%02d and %02d seconds \n\n', app.NameLabel.Text,...
            month(t), day(t), year(t), hour(t), minute(t), floor(second(t)));
            fprintf(handles.logFile,logMessage);
            handles = loadDbParameters(app, handles.currentSqliteOpened,handles);
            guidata(hObject,handles);


        end

        % Button pushed function: ExportDatatoCSV
        function ExportDataToCSV_Callback(app, event)
              [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);%#ok<ASGLU>
                dbPath = handles.currentSqliteOpened;
                dbName = handles.currentSqliteOpenedName;
            
                %— Connect via JDBC
                handles.databasePath = ['jdbc:sqlite:', dbPath];
                conn = database('','','','org.sqlite.JDBC',handles.databasePath);
                handles.conn = conn;
            
                %— Get all table names
                tbls       = fetch(conn, "SELECT name FROM sqlite_master WHERE type='table';");
                tableNames = string(tbls.name);
            
                %— Build map of column→table
                columnTableMap = containers.Map;
                allColumns     = strings(0,1);
                for ti = 1:numel(tableNames)
                    tbl      = tableNames(ti);
                    pragma   = fetch(conn, sprintf('PRAGMA table_info(%s);', tbl));
                    colNames = string(pragma.name);
                    for cj = 1:numel(colNames)
                        col = colNames(cj);
                        if ~strcmpi(col,'ID') && ~strcmpi(col,'Sample ID')
                            key = sprintf('%s.%s', tbl, col);
                            columnTableMap(key) = tbl;
                            allColumns(end+1)   = key; %#ok<AGROW>
                        end
                    end
                end
            
                %— Sort case-insensitive (optional, but keeps consistency)
                [~, idx]   = sort(lower(allColumns));
                allColumns = allColumns(idx); %#ok <NASGU>
            
                %— 1) Pick export columns via table→columns dropdown dialog
                exportColsCell = selectColumnsModal(app,tableNames, columnTableMap, ...
                                       'Select columns to export');
                if isempty(exportColsCell)
                    uialert(app.figure1,'No columns selected.','Cancelled');
                    close(conn); return;
                end
                exportCols = string(exportColsCell);
            
                %— Determine involvedTables
                involvedTables = tableNames;
            
                %— 2) Exclude NULL rows?
                doFilterNull = strcmp( questdlg( ...
                    'Exclude rows where ANY export column is NULL?', ...
                    'Filter NULLs','Yes','No','No'), 'Yes');
            
                    
                %— 3) Conditional filters?
                    doCond = strcmp( questdlg( ...
                        'Add conditional filters?', ...
                        'Conditional Filters','Yes','No','No'), 'Yes');
                    
                    condClauses = {};
                    if doCond
                        % — First: pick which columns to filter on
                        filterColsCell = selectColumnsModal( ...
                             app, ...
                             involvedTables, ...         % tables to choose from
                             columnTableMap, ...
                             'Select columns to filter on');
                        if ~isempty(filterColsCell)
                            % — Then build the actual condition clause
                            clause = filterConditionsModal(app, filterColsCell);
                            if ~isempty(clause)
                                condClauses{end+1} = clause;
                            end
                        end
                    end


                %— Build SELECT + JOIN
                baseTable = involvedTables(1);
                selectList = sprintf('%s.ID', baseTable);
                for i = 1:numel(exportCols) %#ok<FXUP>
                    selectList = sprintf('%s, %s', selectList, exportCols(i));
                end
                joinSQL = sprintf('SELECT %s FROM %s', selectList, baseTable);
                for i = 2:numel(involvedTables) %#ok<FXUP>
                    joinSQL = sprintf('%s LEFT JOIN %s USING(ID)', ...
                                       joinSQL, involvedTables(i));
                end
            
                %— WHERE clause
                whereParts = {};
                if doFilterNull
                    for i = 1:numel(exportCols) %#ok<FXUP>
                        whereParts{end+1} = sprintf('%s IS NOT NULL', exportCols(i)); %#ok<AGROW>
                    end
                end
                if ~isempty(condClauses)
                    whereParts = [whereParts, condClauses];
                end
                if ~isempty(whereParts)
                    % Convert all clauses to a homogeneous string array
                    partsStr = string(whereParts);
                    % Now join them with AND
                    clause   = strjoin(partsStr, ' AND ');
                    joinSQL  = sprintf('%s WHERE %s', joinSQL, clause);
                end
            
                %— Execute query
                try
                    data = fetch(conn, joinSQL);
                catch ME
                    uialert(app.figure1, ['SQL Error: ' ME.message],'Query Error');
                    close(conn); return;
                end
            
                %— Rename dialog (unchanged)…
                origNames    = data.Properties.VariableNames;
                numVars      = numel(origNames);
                prompts      = cell(1, numVars-1);
                for v = 1:numVars
                    if strcmp(origNames{v}, 'ID')
                        fq = sprintf('%s.%s', baseTable, origNames{v});
                    else
                        fq = exportCols(v-1);
                    end
                    if v~=1
                        prompts{v-1} = sprintf('New name for column "%s":', fq);
                    end
                end


                % --- Scrollable Rename Dialog (bigger, skips ID) ---
                    origNames = data.Properties.VariableNames;
                    renameCols = origNames(~strcmp(origNames,'ID'));
                    numVars    = numel(renameCols);
                
                    % Layout
                    rowH   = 30; gap = 10;
                    totalH = numVars*(rowH+gap) + gap;
                    visH   = 500;
                    dlgW   = 900; dlgH = 800;
                
                    % Create the dialog
                    dlg = uifigure(...
                        'Name'    , 'Rename Columns', ...
                        'Position', [200 100 dlgW dlgH], ...
                        'Resize'  , 'off');
                
                    % Scrollable panel
                    pnl = uipanel(dlg, ...
                        'Position'  , [20 200 dlgW-40 visH], ...
                        'Scrollable', 'on');
                
                    % OK & Cancel buttons, pointing to nested functions
                    btnOK = uibutton(dlg, ...  
                        'Text'          , 'OK', ...
                        'Position'      , [dlgW*0.25 50 120 50], ...
                        'FontSize'      , 14, ...
                        'ButtonPushedFcn', @onOK); %#ok<NASGU>
                
                    btnCancel = uibutton(dlg, ...
                        'Text'          , 'Cancel', ...
                        'Position'      , [dlgW*0.6  50 120 50], ...
                        'FontSize'      , 14, ...
                        'ButtonPushedFcn', @onCancel); %#ok<NASGU>
                
                    % Create one label+edit per non‐ID column
                    edits = gobjects(numVars,1);
                    for i = 1:numVars %#ok<FXUP>
                        y = totalH - i*(rowH+gap) + gap;
                        fq = renameCols{i};
                        uilabel(pnl, ...
                            'Text'    , sprintf('Rename "%s" →', fq), ...
                            'Position', [10 y 300 rowH], ...
                            'FontSize', 12);
                        edits(i) = uieditfield(pnl,'text', ...
                            'Position',[320 y dlgW-360 rowH], ...
                            'Value'   , fq, ...
                            'FontSize', 12);
                    end
                
                    uiwait(dlg);
                
                    % If cancelled
                    if ~isgraphics(dlg) || isempty(dlg.UserData)
                        delete(dlg);
                        return;
                    end
                
                    % Collect new names
                    % Collect new names
                    newNames = cell(1,numVars);
                    for i = 1:numVars %#ok <FXUP>
                        v = strtrim(edits(i).Value);
                        if isempty(v)
                            newNames{i} = renameCols{i};
                        else
                            newNames{i} = matlab.lang.makeValidName(v);
                        end
                    end
                    delete(dlg);
                
                    % Apply to data
                    finalNames = data.Properties.VariableNames;
                    for i = 1:numVars %#ok<FXUP>
                        idx = find(strcmp(finalNames, renameCols{i}),1);
                        finalNames{idx} = newNames{i};
                    end
                    data.Properties.VariableNames = finalNames;
                    % --- end scrollable rename dialog ---
                
                    % ─────── Nested callback functions ───────
                    function onOK(~,~)
                        dlg.UserData = true;
                        uiresume(dlg);
                    end
                
                    function onCancel(~,~)
                        dlg.UserData = [];
                        uiresume(dlg);
                    end
                % --- End scrollable rename dialog ---


                if ~isempty(newNames)
                    data.Properties.VariableNames(2:end) = matlab.lang.makeValidName(newNames);
                end


                function clause = filterConditionsModal(app, filterCols)
                    % Opens a dialog to build a compound WHERE clause.
                    % filterCols: cell array of 'table.column' strings
                    % Returns a single SQL clause or '' if cancelled.
                
                    % ───────── Layout parameters ─────────
                    rowH     = 30;   % each row’s height
                    gap      = 10;   % vertical spacing
                    maxRows  = 200;  % allow up to 200 filters
                    totalH   = maxRows*(rowH+gap) + gap;  % total content height
                    visH     = 400;  % visible panel height
                    panelW   = app.figure1.Position(3) - 40;
                    figW     = panelW - 1200;
                    figH     = visH + 200;
                
                    % ───────── Create dialog ─────────
                    fig = uifigure(...
                        'Name'    , 'Build Filter Conditions', ...
                        'Position', [200 100 figW figH], ...
                        'Resize'  , 'off');
                    pnl = uipanel(fig, ...
                        'Position'  , [15 120 panelW-1219 visH], ...
                        'Scrollable', 'on');
                
                    % ───────── Buttons ─────────
                    uibutton(fig,'Text','Add Condition', ...
                        'Position',[20 20 140 30], ...
                        'ButtonPushedFcn',@addCondition);
                    uibutton(fig,'Text','Done', ...
                        'Position',[figW*0.25 20 120 40], ...
                        'ButtonPushedFcn',@onDone);
                    uibutton(fig,'Text','Cancel', ...
                        'Position',[figW*0.45 20 120 40], ...
                        'ButtonPushedFcn',@onCancel);
                
                    % ───────── Storage for dynamic rows ─────────
                    rows = struct('grpL',[],'col',[],'op',[],'val',[],'grpR',[],'conn',[]);
                    nRows = 0;
                
                    % Add the first row
                    addCondition();
                
                    uiwait(fig);
                
                    % If cancelled
                    if ~isgraphics(fig) || isempty(fig.UserData)
                        clause = '';
                        delete(fig);
                        return;
                    end
                
                    % Build the combined clause
                    parts = strings(1,nRows);
                    for i = 1:nRows %#ok<FXUP>
                        p = "";
                        if rows(i).grpL.Value, p = "("; end
                        txt = strtrim(rows(i).val.Value);
                        if ~isempty(txt)
                            lit = rows(i).val.Value;
                            if ~isempty(str2num(lit)) %#ok<ST2NM>
                                % numeric
                            else
                                lit = "'" + lit + "'";
                            end
                            p = p + rows(i).col.Value + " " + rows(i).op.Value + " " + lit;
                            if rows(i).grpR.Value, p = p + ")"; end
                            if i < nRows
                                p = p + " " + rows(i+1).conn.Value + " ";
                            end
                        end
                        parts(i) = p;
                    end
                    clause = "(" + strjoin(parts,"") + ")";
                    delete(fig);
                
                
                    % ───────── Nested callbacks ─────────
                    function addCondition(~,~)
                        nRows = nRows + 1;
                        % position Y relative to totalH
                        yPos = totalH - nRows*(rowH+gap) + gap;
                
                        % Left parenthesis
                        rows(nRows).grpL = uicheckbox(pnl, ...
                            'Text'    ,'(', ...
                            'Position',[10 yPos 25 25]);
                
                        % Column dropdown
                        rows(nRows).col = uidropdown(pnl, ...
                            'Items'    , filterCols, ...
                            'Position' ,[45 yPos 200 25], ...
                            'FontSize' ,12);
                
                        % Operator dropdown
                        rows(nRows).op = uidropdown(pnl, ...
                            'Items'    , {'>','<','>=','<=','=','~='}, ...
                            'Position' ,[255 yPos 60 25], ...
                            'FontSize' ,12);
                
                        % Value field
                        rows(nRows).val = uieditfield(pnl,'text', ...
                            'Position' ,[325 yPos 200 25], ...
                            'FontSize' ,12);
                
                        % Right parenthesis
                        rows(nRows).grpR = uicheckbox(pnl, ...
                            'Text'    ,')', ...
                            'Position',[535 yPos 25 25]);
                
                        % AND/OR connector to next
                        rows(nRows).conn = uidropdown(pnl, ...
                            'Items'    , {'AND','OR'}, ...
                            'Value'    , 'AND', ...
                            'Position' ,[580 yPos 65 25], ...
                            'FontSize' ,12);
                    end
                
                    function onDone(~,~)
                        fig.UserData = true;
                        uiresume(fig);
                    end
                
                    function onCancel(~,~)
                        fig.UserData = [];
                        uiresume(fig);
                    end
                end


                %— CSV file dialog + write
                app.figure1.Visible = 'off';
                [csvFile,csvPath] = uiputfile('*.csv','Save CSV As',[dbName(1:end-7) '.csv']);
                app.figure1.Visible = 'on';
                if isequal(csvFile,0)
                    uialert(app.figure1,'Export cancelled.','Cancelled');
                    close(conn); return;
                end
                writetable(data, fullfile(csvPath,csvFile));
                uialert(app.figure1,'Export complete!','Success');
                close(conn);
                guidata(hObject, handles);
            end
            
            % ─────────────────────────────────────────────────────────────────────────
            function sel = selectColumnsModal(app, tableList, columnTableMap, dlgTitle)
                    % Returns a cell array of 'table.column' strings (or {} if cancelled)
                
                    %— Bigger window
                    fig = uifigure(...
                        'Name'     , dlgTitle, ...
                        'Position' , [100 50 1000 800], ...
                        'Resize'   , 'off');
                
                    %— Table dropdown label + control
                    uilabel(fig, ...
                        'Text'     , 'Select Table:', ...
                        'Position' , [20 750 120 30], ...
                        'FontSize' , 14);
                    dd = uidropdown(fig, ...
                        'Items'          , tableList, ...
                        'Position'       , [150 755 400 30], ...
                        'FontSize'       , 14, ...
                        'ValueChangedFcn', @updateCols);
                
                    %— Available columns label + list
                    uilabel(fig, ...
                        'Text'     , 'Available Columns:', ...
                        'Position' , [20 710 200 30], ...
                        'FontSize' , 14);
                    lbCols = uilistbox(fig, ...
                        'Position'    , [20 100 450 600], ...
                        'Multiselect' , 'on', ...
                        'FontSize'    , 14);
                
                    %— Selected columns label + list
                    uilabel(fig, ...
                        'Text'     , 'Selected Columns:', ...
                        'Position' , [530 710 200 30], ...
                        'FontSize' , 14);
                    lbSel = uilistbox(fig, ...
                        'Items'       , {}, ...     % start empty
                        'Value'       , {}, ...     % no selection
                        'Position'    , [530 100 450 600], ...
                        'Multiselect' , 'off', ...
                        'FontSize'    , 14);
                
                    %— Add, Done, and Cancel buttons
                    uibutton(fig, ...
                        'Text'          , 'Add →', ...
                        'Position'      , [200 50 120 40], ...
                        'FontSize'      , 14, ...
                        'ButtonPushedFcn', @addCols);
                
                    uibutton(fig, ...
                        'Text'          , 'Done', ...
                        'Position'      , [350 50 120 40], ...
                        'FontSize'      , 14, ...
                        'ButtonPushedFcn', @doDone);
                
                    uibutton(fig, ...
                        'Text'          , 'Clear', ...
                        'Position'      , [550 50 120 40], ...
                        'FontSize'      , 14, ...
                        'ButtonPushedFcn', @doClear);

                    uibutton(fig, ...
                        'Text'          , 'Cancel', ...
                        'Position'      , [750 50 120 40], ...
                        'FontSize'      , 14, ...
                        'ButtonPushedFcn', @doCancel);
                
                    %— Populate initial columns list
                    updateCols();
                
                    %— Block until user clicks Done/Cancel
                    uiwait(fig);
                
                    if (isgraphics(fig))
                        sel = fig.UserData;
                    else
                        sel = {};
                    end

                    delete(fig);


                    %— Callback: update available columns when table changes
                    function updateCols(~, ~)
                        tbl   = dd.Value;
                        keysA = keys(columnTableMap);
                        valsA = values(columnTableMap, keysA);
                        mask  = string(valsA) == tbl;
                        lbCols.Items = keysA(mask);
                    end
                
                    %— Callback: add selected items to the "Selected" list
                    function addCols(~, ~)
                        toAdd = lbCols.Value;
                        curr  = lbSel.Items;
                        new   = setdiff(toAdd, curr, 'stable');
                        lbSel.Items = horzcat(curr, new);
                    end
                
                    %— Callback: finish with current selection
                    function doDone(~, ~)
                        fig.UserData = lbSel.Items;
                        uiresume(fig);
                    end

                function doClear(~,~)
                    fig.UserData = {};
                    lbSel.Items = {};
                end
                
                    %— Callback: cancel selection
                    function doCancel(~, ~)
                        fig.UserData = {};
                        uiresume(fig);
                    end

                app.figure1.Visible = 'off';
               app.figure1.Visible = 'on';
        end

        % Button pushed function: ChangeBinAmount
        function ChangeBinAmount_Callback(app, event)
                % create GUIDE args
                [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % ask the user
                prompt   = {'Enter desired number of bins:'};
                dlgtitle = 'Set Number of Bins';
                dims     = [1 35];
                definput = { num2str(handles.nBins) };
                answer   = inputdlg(prompt, dlgtitle, dims, definput);
            
                if isempty(answer)
                    return;  % user cancelled
                end
            
                % validate and store
                nb = str2double(answer{1});
                if isnan(nb) || nb <= 0
                    errordlg('Please enter a positive integer','Invalid Input');
                    return;
                end
                handles.nBins = round(nb);
            
                % update the label
                set(app.NumberOfBinsLabel, ...
                    'Text', sprintf('Number of Bins: %d', handles.nBins));
            
                guidata(hObject, handles);
        end

        % Value changed function: histogramCheckBox
        function histogramCheckBox_Callback(app, event)
                               % --- Executes when histogramCheckBox is toggled ---
                [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1) Clear any existing stat boxes
                if isfield(handles, 'histStatsTexts')
                    delete(handles.histStatsTexts(isvalid(handles.histStatsTexts)));
                end
                handles.histStatsTexts = gobjects(0,1);
            
                % 2) If checkbox is turned on, create new boxes
                if app.histogramCheckBox.Value == 1
                    ax    = handles.mainAxes;
                    xl    = ax.XLim;
                    yl    = ax.YLim;
                    xPos  = xl(1) + 0.02*(xl(2)-xl(1));
                    yTop  = yl(2) - 0.02*(yl(2)-yl(1));
                    vStep = 0.10*(yl(2)-yl(1));  % vertical spacing between boxes
            
                    % Loop over every histogram’s data & column name
                    for i = 1:numel(handles.histData)
                        vec     = handles.histData{i};
                        colName = handles.histCols{i};  % must have been stored alongside histData
            
                        % compute statistics
                        mu     = mean(vec);
                        sigma  = std(vec);
                        % KS test against Normal(µ,σ)
                        [~, pVal] = kstest((vec - mu) / sigma);
            
                        % build the display string
                        str = sprintf([...
                            '%s:\n' ...
                            'Mean = %.3f\n' ...
                            'Standard deviation = %.3f\n' ...
                            'Kolmogorov–Smirnov p-value = %.3g'], ...
                            colName, mu, sigma, pVal);
            
                        % position each box in its own row
                        yPos = yTop - (i-1) * vStep;
            
                        % draw the text box
                        txt = text(handles.mainAxes, ...
                            xPos, yPos, str, ...
                            'Parent',              ax, ...
                            'Units',               'data', ...
                            'VerticalAlignment',   'top', ...
                            'HorizontalAlignment', 'left', ...
                            'EdgeColor',           'k', ...
                            'BackgroundColor',     'w', ...
                            'FontSize',            11);
            
                        % collect handles for later deletion
                        handles.histStatsTexts(end+1) = txt;
                    end
                end
            
                % 3) Save updated handles
                guidata(hObject, handles);

            
        end

        % Window key press function: figure1
        function keyPress_Callback(app, event)
          %  key = event.Key;
                 if any(strcmp(event.Modifier, "control"))
                        switch event.Key
                            case "h"  % Ctrl‑H → horizontal only
                                if app.mainAxes.InteractionOptions.LimitsDimensions ~= "x"
                                    app.mainAxes.InteractionOptions.LimitsDimensions = "x";
                                end
                            case "v"  % Ctrl‑V → vertical only
                                if app.mainAxes.InteractionOptions.LimitsDimensions ~= "y"
                                    app.mainAxes.InteractionOptions.LimitsDimensions = "y";
                                end
                            case "t"
                               app.mainAxes.InteractionOptions.LimitsDimensions = "xy"; 
                        end
                  end
            
        end

        % Window key release function: figure1
        function keyUp_Callback(app, event)
           % key = event.Key;
                                % When H or V is released, restore both dims
                   % if any(strcmp(event.Key, ["h","v"]))
                   %     app.mainAxes.InteractionOptions.LimitsDimensions = "xy";
                  %  end
            
        end

        % Value changed function: OnlyIncludeSameTableFiltersCheckBox
        function OnlyIncludeSameTableFilters_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            updateinclCategoriesList(app, handles, handles.yAxisParamList.String{handles.yAxisParamList.Value});
        end

        % Value changed function: YAxisParametersFilterEditField
        function YAxisParametersFilterEdit_Callback(app, event)
            yAxisParamList_Callback(app,event);
        end

        % Value changed function: XAxisParametersFilterEditField
        function XAxisParametersFilterEdit_Callback(app, event)
            xAxisParamList_Callback(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SQLite Explorer';
            app.figure1.WindowKeyPressFcn = createCallbackFcn(app, @keyPress_Callback, true);
            app.figure1.WindowKeyReleaseFcn = createCallbackFcn(app, @keyUp_Callback, true);
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool2 = uipushtool(app.uitoolbar1);
            app.uipushtool2.Tag = 'uipushtool2';
            app.uipushtool2.Tooltip = 'Save Figure';
            app.uipushtool2.Icon = 'uipushtool2_image.png';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Plot';
            app.uipanel5.Tag = 'uipanel5';
            app.mainAxes = uiaxes(app.uipanel5);
            app.mainAxes.XLim = [0.5 6.5];
            app.mainAxes.YLim = [691.60293 1868.44607];
            app.mainAxes.XTick = [800 1000 1200 1400 1600 1800];
            app.mainAxes.Tag = 'mainAxes';
            app.clearPlotButton = uibutton(app.uipanel5, 'push');
            app.clearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @clearPlotButton_Callback, true);
            app.clearPlotButton.Tag = 'clearPlotButton';
            app.clearPlotButton.Enable = 'off';
            app.clearPlotButton.Text = 'CLEAR PLOT';
            app.saveButton = uibutton(app.uipanel5, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Tag = 'saveButton';
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'SAVE PLOT';
            app.uipanel13 = uipanel(app.uipanel5);
            app.uipanel13.Tag = 'uipanel13';
            app.scatterCheckBox = uicheckbox(app.uipanel13);
            app.scatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @scatterCheckBox_Callback, true);
            app.scatterCheckBox.Tag = 'scatterCheckBox';
            app.scatterCheckBox.Enable = 'off';
            app.scatterCheckBox.Text = 'Display Additional Scatter Plot Information';
            app.statsPositionMenu = uidropdown(app.uipanel13);
            app.statsPositionMenu.Items = {'Top', 'Bottom'};
            app.statsPositionMenu.ValueChangedFcn = createCallbackFcn(app, @statsPositionMenu_Callback, true);
            app.statsPositionMenu.Tag = 'statsPositionMenu';
            app.statsPositionMenu.Enable = 'off';
            app.statsPositionMenu.Value = 'Bottom';
            app.text29 = uilabel(app.uipanel13);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Position:';
            app.boxCheckBox = uicheckbox(app.uipanel13);
            app.boxCheckBox.ValueChangedFcn = createCallbackFcn(app, @boxCheckBox_Callback, true);
            app.boxCheckBox.Tag = 'boxCheckBox';
            app.boxCheckBox.Enable = 'off';
            app.boxCheckBox.Text = 'Display Additional Box Plot Information';
            app.histogramCheckBox = uicheckbox(app.uipanel13);
            app.histogramCheckBox.ValueChangedFcn = createCallbackFcn(app, @histogramCheckBox_Callback, true);
            app.histogramCheckBox.Tag = 'boxCheckBox';
            app.histogramCheckBox.Visible = 'off';
            app.histogramCheckBox.Text = 'Display Additional Histogram Information';
            app.editTitlesButton = uibutton(app.uipanel5, 'push');
            app.editTitlesButton.ButtonPushedFcn = createCallbackFcn(app, @editTitlesButton_Callback, true);
            app.editTitlesButton.Tag = 'editTitlesButton';
            app.editTitlesButton.Enable = 'off';
            app.editTitlesButton.Text = 'EDIT TITLES/LABELS';
            app.ChangeBinAmount = uibutton(app.uipanel5, 'push');
            app.ChangeBinAmount.ButtonPushedFcn = createCallbackFcn(app, @ChangeBinAmount_Callback, true);
            app.ChangeBinAmount.Visible = 'off';
            app.ChangeBinAmount.Text = 'Change Number of Bins';
            app.NumberOfBinsLabel = uilabel(app.uipanel5);
            app.NumberOfBinsLabel.Visible = 'off';
            app.NumberOfBinsLabel.Text = 'Number of Bins: ';
            app.ColumnSelectorPanel = uipanel(app.uipanel5);
            app.ColumnSelectorPanel.Title = 'Panel2';
            app.ColumnSelectorPanel.Visible = 'off';
            app.OverrideData = uicheckbox(app.ColumnSelectorPanel);
            app.OverrideData.Text = 'Override Data in Existing Columns';
            app.OverrideData.Value = true;
            app.DoImportButton = uibutton(app.ColumnSelectorPanel, 'push');
            app.DoImportButton.Text = 'Import';
            app.ColumnScrollPanel = uipanel(app.ColumnSelectorPanel);
            app.ColumnScrollPanel.Title = 'Panel3';
            app.ExitImportCSV = uibutton(app.ColumnSelectorPanel, 'push');
            app.ExitImportCSV.ButtonPushedFcn = createCallbackFcn(app, @ExitImportCSV_Callback, true);
            app.ExitImportCSV.Text = 'Exit Import CSV';
            app.tblLabel = uilabel(app.ColumnSelectorPanel);
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Select Groups to Plot';
            app.uipanel4.Tag = 'uipanel4';
            app.markerColorText = uilabel(app.uipanel4);
            app.markerColorText.Tag = 'markerColorText';
            app.markerColorText.Text = 'Box Color';
            app.markerTypeDropdown = uidropdown(app.uipanel4);
            app.markerTypeDropdown.Items = {'o', '+', '*', '.', 'x'};
            app.markerTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @markerTypeDropdown_Callback, true);
            app.markerTypeDropdown.Tag = 'markerTypeDropdown';
            app.markerTypeDropdown.Enable = 'off';
            app.markerTypeDropdown.Value = 'o';
            app.markerTypeText = uilabel(app.uipanel4);
            app.markerTypeText.Tag = 'markerTypeText';
            app.markerTypeText.Text = 'Marker Type';
            app.markerColorDropdown = uidropdown(app.uipanel4);
            app.markerColorDropdown.Items = {'Red', 'Yellow', 'magenta', 'Cyan', 'Green', 'Blue', 'Black'};
            app.markerColorDropdown.ValueChangedFcn = createCallbackFcn(app, @markerColorDropdown_Callback, true);
            app.markerColorDropdown.Tag = 'markerColorDropdown';
            app.markerColorDropdown.Enable = 'off';
            app.markerColorDropdown.Value = 'Red';
            app.plotButton = uibutton(app.uipanel4, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButton_Callback, true);
            app.plotButton.Tag = 'plotButton';
            app.plotButton.Enable = 'off';
            app.plotButton.Text = 'PLOT';
            app.uipanel14 = uipanel(app.uipanel4);
            app.uipanel14.Tag = 'uipanel14';
            app.onlyIncludeText = uilabel(app.uipanel14);
            app.onlyIncludeText.Tag = 'onlyIncludeText';
            app.onlyIncludeText.Text = 'Current Filters Options';
            app.inclCategoriesList = uilistbox(app.uipanel14);
            app.inclCategoriesList.Items = {};
            app.inclCategoriesList.ValueChangedFcn = createCallbackFcn(app, @inclCategoriesList_Callback, true);
            app.inclCategoriesList.Tag = 'inclCategoriesList';
            app.inclCategoriesList.Enable = 'off';
            app.inclCategoriesList.Value = {};
            app.divideSlider = uislider(app.uipanel14);
            app.divideSlider.Limits = [0 1];
            app.divideSlider.MajorTicks = [];
            app.divideSlider.MinorTicks = [0 0.0475 0.095 0.1425 0.19 0.2375 0.285 0.3325 0.38 0.4275 0.475 0.5225 0.57 0.6175 0.665 0.7125 0.76 0.8075 0.855 0.9025 0.95 1];
            app.divideSlider.Step = 1;
            app.divideSlider.Tag = 'divideSlider';
            app.divideSlider.Enable = 'off';
            app.divideSlider.Visible = 'off';
            app.divideEditText = uieditfield(app.uipanel14, 'text');
            app.divideEditText.Tag = 'divideEditText';
            app.divideEditText.Enable = 'off';
            app.divideEditText.Visible = 'off';
            app.divideEditText.Value = '0';
            app.addCategoryButton = uibutton(app.uipanel14, 'push');
            app.addCategoryButton.ButtonPushedFcn = createCallbackFcn(app, @addCategoryButton_Callback, true);
            app.addCategoryButton.Tag = 'addCategoryButton';
            app.addCategoryButton.Enable = 'off';
            app.addCategoryButton.Text = 'MODIFY';
            app.uipanel15 = uipanel(app.uipanel14);
            app.uipanel15.Title = 'Panel';
            app.uipanel15.Tag = 'uipanel15';
            app.text28 = uilabel(app.uipanel14);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Narrowed by:';
            app.narrowedList = uilistbox(app.uipanel14);
            app.narrowedList.Items = {''};
            app.narrowedList.Multiselect = 'on';
            app.narrowedList.Tag = 'narrowedList';
            app.narrowedList.Enable = 'off';
            app.narrowedList.Value = {};
            app.clearNarrowButton = uibutton(app.uipanel14, 'push');
            app.clearNarrowButton.ButtonPushedFcn = createCallbackFcn(app, @clearNarrowButton_Callback, true);
            app.clearNarrowButton.Tag = 'clearNarrowButton';
            app.clearNarrowButton.Enable = 'off';
            app.clearNarrowButton.Text = 'CLEAR LIST';
            app.ignoreZerosCheckbox = uicheckbox(app.uipanel14);
            app.ignoreZerosCheckbox.Tag = 'ignoreZerosCheckbox';
            app.ignoreZerosCheckbox.Enable = 'off';
            app.ignoreZerosCheckbox.Text = 'Ignore Zeros';
            app.OnlyIncludeSameTableFiltersCheckBox = uicheckbox(app.uipanel4);
            app.OnlyIncludeSameTableFiltersCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlyIncludeSameTableFilters_Callback, true);
            app.OnlyIncludeSameTableFiltersCheckBox.Text = 'Only Include Same Table Filters';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Plot Type';
            app.uipanel1.Tag = 'uipanel1';
            app.plotTypeDropdown = uidropdown(app.uipanel1);
            app.plotTypeDropdown.Items = {'Scatter Plot', 'Box Plot', 'Histogram'};
            app.plotTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @plotTypeDropdown_Callback, true);
            app.plotTypeDropdown.Tag = 'plotTypeDropdown';
            app.plotTypeDropdown.Enable = 'off';
            app.plotTypeDropdown.Value = 'Scatter Plot';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Parameters to Plot';
            app.uipanel7.Tag = 'uipanel7';
            app.xAxisParamList = uilistbox(app.uipanel7);
            app.xAxisParamList.Items = {''};
            app.xAxisParamList.ValueChangedFcn = createCallbackFcn(app, @xAxisParamList_Callback, true);
            app.xAxisParamList.Tag = 'xAxisParamList';
            app.xAxisParamList.Enable = 'off';
            app.xAxisParamList.Value = '';
            app.xAxisText = uilabel(app.uipanel7);
            app.xAxisText.Tag = 'xAxisText';
            app.xAxisText.Text = 'Table';
            app.yAxisParamList = uilistbox(app.uipanel7);
            app.yAxisParamList.Items = {''};
            app.yAxisParamList.ValueChangedFcn = createCallbackFcn(app, @yAxisParamList_Callback, true);
            app.yAxisParamList.Tag = 'yAxisParamList';
            app.yAxisParamList.Enable = 'off';
            app.yAxisParamList.Value = '';
            app.xAxisParamList2 = uilistbox(app.uipanel7);
            app.xAxisParamList2.Items = {''};
            app.xAxisParamList2.Multiselect = 'on';
            app.xAxisParamList2.ValueChangedFcn = createCallbackFcn(app, @xAxisParamList2_Callback, true);
            app.xAxisParamList2.Tag = 'xAxisParamList2';
            app.xAxisParamList2.Enable = 'off';
            app.xAxisParamList2.Value = {''};
            app.yAxisParamList2 = uilistbox(app.uipanel7);
            app.yAxisParamList2.Items = {''};
            app.yAxisParamList2.Multiselect = 'on';
            app.yAxisParamList2.ValueChangedFcn = createCallbackFcn(app, @yAxisParamList2_Callback, true);
            app.yAxisParamList2.Tag = 'yAxisParamList2';
            app.yAxisParamList2.Enable = 'off';
            app.yAxisParamList2.Value = {''};
            app.text17 = uilabel(app.uipanel7);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Parameter';
            app.text21 = uilabel(app.uipanel7);
            app.text21.Tag = 'text21';
            app.text21.Text = 'X-Axis';
            app.text22 = uilabel(app.uipanel7);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Y-Axis';
            app.text23 = uilabel(app.uipanel7);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Table';
            app.text24 = uilabel(app.uipanel7);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Parameter';
            app.y2GroupButton = uibutton(app.uipanel7, 'push');
            app.y2GroupButton.ButtonPushedFcn = createCallbackFcn(app, @y2GroupButton_Callback, true);
            app.y2GroupButton.Tag = 'y2GroupButton';
            app.y2GroupButton.Enable = 'off';
            app.y2GroupButton.Text = 'Add Y Parameter to Group List';
            app.text31 = uilabel(app.uipanel7);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Convert to Log?';
            app.logXCheckbox = uicheckbox(app.uipanel7);
            app.logXCheckbox.Tag = 'logXCheckbox';
            app.logXCheckbox.Enable = 'off';
            app.logXCheckbox.Text = 'X-Axis';
            app.logYCheckbox = uicheckbox(app.uipanel7);
            app.logYCheckbox.Tag = 'logYCheckbox';
            app.logYCheckbox.Enable = 'off';
            app.logYCheckbox.Text = 'Y-Axis';
            app.XAxisParametersFilterPhraseEditFieldLabel = uilabel(app.uipanel7);
            app.XAxisParametersFilterPhraseEditFieldLabel.Text = 'X-Axis-Parameters Filter Phrase';
            app.XAxisParametersFilterEditField = uieditfield(app.uipanel7, 'text');
            app.XAxisParametersFilterEditField.ValueChangedFcn = createCallbackFcn(app, @XAxisParametersFilterEdit_Callback, true);
            app.YAxisParametersFilterPhraseEditFieldLabel = uilabel(app.uipanel7);
            app.YAxisParametersFilterPhraseEditFieldLabel.Text = 'Y-Axis-Parameters Filter Phrase';
            app.YAxisParametersFilterEditField = uieditfield(app.uipanel7, 'text');
            app.YAxisParametersFilterEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisParametersFilterEdit_Callback, true);
            app.YesSumColumnNoAverageColumnCheckBox = uicheckbox(app.uipanel7);
            app.YesSumColumnNoAverageColumnCheckBox.Text = 'Yes: Sum Column, No:  Average Column';
            app.AverageRowsCheckBox = uicheckbox(app.uipanel7);
            app.AverageRowsCheckBox.Text = 'Average Rows of Same ID';
            app.selDbPanel = uipanel(app.figure1);
            app.selDbPanel.Title = 'Select Database';
            app.selDbPanel.Tag = 'selDbPanel';
            app.selectDbMenu = uidropdown(app.selDbPanel);
            app.selectDbMenu.Items = {'MREDatabase_MRICloud.sqlite', 'soccerStudy.sqlite'};
            app.selectDbMenu.ValueChangedFcn = createCallbackFcn(app, @selectDbMenu_Callback, true);
            app.selectDbMenu.Tag = 'selectDbMenu';
            app.selectDbMenu.Value = 'MREDatabase_MRICloud.sqlite';
            app.AddSqliteFiles = uibutton(app.selDbPanel, 'push');
            app.AddSqliteFiles.ButtonPushedFcn = createCallbackFcn(app, @AddSqliteFiles_Callback, true);
            app.AddSqliteFiles.Text = 'Add .sqlite Files';
            app.RevertChanges = uibutton(app.selDbPanel, 'push');
            app.RevertChanges.ButtonPushedFcn = createCallbackFcn(app, @RevertChanges_Callback, true);
            app.RevertChanges.Enable = 'off';
            app.RevertChanges.Text = 'Revert All Changes to .sqlite Files ';
            app.CSVImportButton = uibutton(app.figure1, 'push');
            app.CSVImportButton.ButtonPushedFcn = createCallbackFcn(app, @CSVImportButton_Callback, true);
            app.CSVImportButton.Enable = 'off';
            app.CSVImportButton.Text = {'Import data from '; 'CSV'};
            app.NameLabel = uilabel(app.figure1);
            app.NameLabel.Text = 'No Name Selected';
            app.EditNameButton = uibutton(app.figure1, 'push');
            app.EditNameButton.ButtonPushedFcn = createCallbackFcn(app, @EditNameButton_Callback, true);
            app.EditNameButton.Text = 'Enter Name';
            app.ExportDatatoCSV = uibutton(app.figure1, 'push');
            app.ExportDatatoCSV.ButtonPushedFcn = createCallbackFcn(app, @ExportDataToCSV_Callback, true);
            app.ExportDatatoCSV.Enable = 'off';
            app.ExportDatatoCSV.Text = 'Export data to CSV';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SQLite_Explorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MREDataReviewer_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end